classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Label_20     matlab.ui.control.Label
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Label_23     matlab.ui.control.Label
        Label_18     matlab.ui.control.Label
        Label_11     matlab.ui.control.Label
        Panel_4      matlab.ui.container.Panel
        Button_11    matlab.ui.control.Button
        Panel_5      matlab.ui.container.Panel
        Button_6     matlab.ui.control.Button
        Button_7     matlab.ui.control.Button
        Button_5     matlab.ui.control.Button
        Button_12    matlab.ui.control.Button
        Panel_2      matlab.ui.container.Panel
        sLabel       matlab.ui.control.Label
        Label_58     matlab.ui.control.Label
        Panel_8      matlab.ui.container.Panel
        Label_56     matlab.ui.control.Label
        Panel_7      matlab.ui.container.Panel
        Label_57     matlab.ui.control.Label
        Label_55     matlab.ui.control.Label
        Panel_6      matlab.ui.container.Panel
        Label_54     matlab.ui.control.Label
        Label_53     matlab.ui.control.Label
        Label_52     matlab.ui.control.Label
        Label_51     matlab.ui.control.Label
        Panel        matlab.ui.container.Panel
        Button_14    matlab.ui.control.Button
        Button_13    matlab.ui.control.Button
        Panel_3      matlab.ui.container.Panel
        Lamp_23      matlab.ui.control.Lamp
        Lamp_22      matlab.ui.control.Lamp
        Lamp_21      matlab.ui.control.Lamp
        Lamp_20      matlab.ui.control.Lamp
        Lamp_19      matlab.ui.control.Lamp
        Lamp_18      matlab.ui.control.Lamp
        Lamp_17      matlab.ui.control.Lamp
        Lamp_16      matlab.ui.control.Lamp
        Lamp_15      matlab.ui.control.Lamp
        Lamp_14      matlab.ui.control.Lamp
        Lamp_13      matlab.ui.control.Lamp
        Lamp_12      matlab.ui.control.Lamp
        Lamp_11      matlab.ui.control.Lamp
        Lamp_10      matlab.ui.control.Lamp
        Lamp_9       matlab.ui.control.Lamp
        Lamp_8       matlab.ui.control.Lamp
        Lamp_7       matlab.ui.control.Lamp
        Lamp_6       matlab.ui.control.Lamp
        Lamp_5       matlab.ui.control.Lamp
        Lamp_4       matlab.ui.control.Lamp
        Lamp_3       matlab.ui.control.Lamp
        Lamp_2       matlab.ui.control.Lamp
        Lamp         matlab.ui.control.Lamp
        Label_50     matlab.ui.control.Label
        mPasLabel_2  matlab.ui.control.Label
        kgmLabel     matlab.ui.control.Label
        mLabel_7     matlab.ui.control.Label
        rminLabel    matlab.ui.control.Label
        mPasLabel    matlab.ui.control.Label
        Label_44     matlab.ui.control.Label
        NmLabel      matlab.ui.control.Label
        MPaLabel     matlab.ui.control.Label
        Label_41     matlab.ui.control.Label
        m3dLabel     matlab.ui.control.Label
        mmLabel      matlab.ui.control.Label
        PaLabel      matlab.ui.control.Label
        mLabel_8     matlab.ui.control.Label
        mLabel_9     matlab.ui.control.Label
        mLabel_10    matlab.ui.control.Label
        mLabel_11    matlab.ui.control.Label
        mLabel_12    matlab.ui.control.Label
        mLabel_13    matlab.ui.control.Label
        mLabel_14    matlab.ui.control.Label
        mLabel_15    matlab.ui.control.Label
        mLabel_16    matlab.ui.control.Label
        mLabel_17    matlab.ui.control.Label
        CheckBox_23  matlab.ui.control.CheckBox
        CheckBox_22  matlab.ui.control.CheckBox
        CheckBox_21  matlab.ui.control.CheckBox
        CheckBox_20  matlab.ui.control.CheckBox
        CheckBox_19  matlab.ui.control.CheckBox
        CheckBox_18  matlab.ui.control.CheckBox
        CheckBox_17  matlab.ui.control.CheckBox
        CheckBox_16  matlab.ui.control.CheckBox
        CheckBox_15  matlab.ui.control.CheckBox
        CheckBox_14  matlab.ui.control.CheckBox
        CheckBox_13  matlab.ui.control.CheckBox
        CheckBox_12  matlab.ui.control.CheckBox
        CheckBox_11  matlab.ui.control.CheckBox
        CheckBox_10  matlab.ui.control.CheckBox
        CheckBox_9   matlab.ui.control.CheckBox
        CheckBox_8   matlab.ui.control.CheckBox
        CheckBox_7   matlab.ui.control.CheckBox
        CheckBox_6   matlab.ui.control.CheckBox
        CheckBox_5   matlab.ui.control.CheckBox
        CheckBox_4   matlab.ui.control.CheckBox
        CheckBox_3   matlab.ui.control.CheckBox
        CheckBox_2   matlab.ui.control.CheckBox
        CheckBox     matlab.ui.control.CheckBox
        Button_15    matlab.ui.control.Button
        TextArea_8   matlab.ui.control.TextArea
        Label_8      matlab.ui.control.Label
        TextArea_7   matlab.ui.control.TextArea
        Label_7      matlab.ui.control.Label
        TextArea_2   matlab.ui.control.TextArea
        Label_2      matlab.ui.control.Label
        TextArea     matlab.ui.control.TextArea
        Label        matlab.ui.control.Label
        TextArea_25  matlab.ui.control.TextArea
        Label_27     matlab.ui.control.Label
        Button_8     matlab.ui.control.Button
        Button_9     matlab.ui.control.Button
        TextArea_24  matlab.ui.control.TextArea
        Label_26     matlab.ui.control.Label
        TextArea_23  matlab.ui.control.TextArea
        Label_25     matlab.ui.control.Label
        TextArea_22  matlab.ui.control.TextArea
        Label_24     matlab.ui.control.Label
        TextArea_19  matlab.ui.control.TextArea
        Label_22     matlab.ui.control.Label
        TextArea_18  matlab.ui.control.TextArea
        Label_21     matlab.ui.control.Label
        Label_19     matlab.ui.control.Label
        TextArea_17  matlab.ui.control.TextArea
        TextArea_16  matlab.ui.control.TextArea
        Label_17     matlab.ui.control.Label
        TextArea_15  matlab.ui.control.TextArea
        Label_16     matlab.ui.control.Label
        TextArea_14  matlab.ui.control.TextArea
        Label_15     matlab.ui.control.Label
        TextArea_13  matlab.ui.control.TextArea
        Label_14     matlab.ui.control.Label
        TextArea_12  matlab.ui.control.TextArea
        Label_13     matlab.ui.control.Label
        TextArea_11  matlab.ui.control.TextArea
        Label_12     matlab.ui.control.Label
        TextArea_10  matlab.ui.control.TextArea
        Label_10     matlab.ui.control.Label
        TextArea_9   matlab.ui.control.TextArea
        Label_9      matlab.ui.control.Label
        TextArea_6   matlab.ui.control.TextArea
        Label_6      matlab.ui.control.Label
        TextArea_5   matlab.ui.control.TextArea
        Label_5      matlab.ui.control.Label
        TextArea_4   matlab.ui.control.TextArea
        Label_4      matlab.ui.control.Label
        TextArea_3   matlab.ui.control.TextArea
        Label_3      matlab.ui.control.Label
    end

    
    properties (Access = public)
        D1o  double = 0; % 抽油杆外径的属性，初始值为 0
        L    double =0;  % 抽油杆的长度
        D2i  double = 0; % 油管内径的属性，初始值为 0
        th1  double =0;  % 抽油杆壁厚
        tt3  double = 0; % 反转时间
        vv3  double = 0; % 反转转速
        Mt   double =0;  % 制动扭矩
    end
    
    methods (Access = public)
        % Notice: 将m或者mlx的功能函数翻译放在这里时候最开始要多加一个传参~
        function [max_abs_Te,max_time,result_powered,t_min]=NengHaoZhiDong(~,c_e,c_T,a,b,R,rou,D1_out,D1_in,pi_val,W1)
                syms theta(t) t
                I_p1 = pi_val / 32 * (D1_out^4 - D1_in^4); % 抽油杆极惯性矩, m^4
                % 计算中间量
                LL = sqrt(a * b);
                Ss = 3.14 * a * b;
                AB = 4.15;
                Kk = (LL^0.25 / AB / Ss)^0.8;

                % 定义电磁转矩 Te
                Te = (c_e * c_T / R) * diff(theta, t);

                % 定义微分方程
                eq2 = diff(theta, t, 2) == -W1^2 * theta - Te / (rou * I_p1);

                % 求解微分方程
                solution_eq2 = dsolve(eq2, theta(0) == 5385 * pi / 180, subs(diff(theta), t, 0) == 0);

                % 计算 theta(t) 的一阶和二阶导数
                dtheta_dt = diff(solution_eq2, t);
                d2theta_dt2 = diff(dtheta_dt, t);

                % 将导数和二阶导数转换为函数
                dtheta_dt_func = matlabFunction(dtheta_dt);
                d2theta_dt2_func = matlabFunction(d2theta_dt2);

                % 定义时间范围
                t_range = linspace(0, 100, 1000); % 从 0 到 100，共 1000 个点

                % 计算导数和二阶导数的数值
                dtheta_dt_values = dtheta_dt_func(t_range);
                d2theta_dt2_values = d2theta_dt2_func(t_range);

                % 找到导数小于 0.1 且二阶导数小于 0.1 的时间点
                valid_indices = find(abs(dtheta_dt_values) < 0.1 & abs(d2theta_dt2_values) < 0.1);
                if ~isempty(valid_indices)
                    t_min = t_range(valid_indices(1)); % 取第一个满足条件的时间点
                else
                    t_min = NaN; % 如果没有找到符合条件的时间点
                end

                % 计算电磁转矩 Te 的数值
                Te_values = (c_e * c_T / R) * dtheta_dt_values;

                % 计算 Te 的绝对值
                abs_Te = 360 * abs(Te_values);

                % 寻找 |Te| 的最大值
                [max_abs_Te, idx] = max(abs_Te);
                max_time = t_range(idx);

                % 计算 theta(t) 的导数的最大值
                dtheta_dt_max = max(abs(dtheta_dt_values));

                % 计算制动时间乘上电磁转矩的绝对值最大值再乘上 theta 导数的最大值
                if ~isnan(t_min)
                    result = t_min * max_abs_Te * dtheta_dt_max / 360 / 30;
                    % 计算 Kk 乘上结果的 0.8 次方
                    result_powered = 20 + Kk * result^0.8;
                else
                    result = NaN; % 如果没有找到符合条件的制动时间
                    result_powered = NaN;
                end

                % 显示结果
                if ~isnan(t_min)

                    disp(['制动时间: ', num2str(t_min), ' s']);
                else
                    disp('没有找到满足条件的制动时间。');
                end
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            
            % 直接从编辑框控件获取值
            % 从app实例的TextArea_10标识符的Value属性中提取值
            
            % 读取用户输入的值
            D1o = str2double(app.TextArea.Value); % 抽油杆外径
            th1 = str2double(app.TextArea_2.Value); % 抽油杆壁厚
            D2o = str2double(app.TextArea_3.Value); % 油管外径
            th2 = str2double(app.TextArea_4.Value); % 油管壁厚
            D3o = str2double(app.TextArea_5.Value); % 套管外径
            th3 = str2double(app.TextArea_6.Value); % 套管壁厚
            H1 = str2double(app.TextArea_7.Value); % 油管内液面高度
            H2 = str2double(app.TextArea_8.Value); % 套管内液面高度
            e = str2double(app.TextArea_9.Value); % 螺杆泵偏心距
            D = str2double(app.TextArea_10.Value); % 螺杆泵截面直径


            % 计算内径
            D1i = D1o - 2 * th1; % 抽油杆内径
            D2i = D2o - 2 * th2; % 油管内径
            D3i = D3o - 2 * th3; % 套管内径
            
            app.D2i = D2i; % 将计算值存储为app属性
            app.D1o = D1o; % 将计算值存储为app属性方便其他功能组件调用
            app.th1 = th1; % 将壁厚值存储为app公共变量池中


            % 计算横截面积
            A1 = (pi/4) * (D2i^2 - D1o^2); % 油管内油液环空横截面积
            A2 = (pi/4) * (D3i^2 - D2o^2); % 套管内油液环空横截面积
            A3 = 4 * e * D; % 螺杆泵横截面积

            % 计算液面高度差
            DeltaH = H1 - H2;

            
            % % 创建一个字符串来存储所有的结果
            % results = sprintf([
            %     '抽油杆内径 D1i: %.4f m^2\n' ...
            %     '油管内径 D2i: %.4f m^2\n' ...
            %     '套管内径 D3i: %.4f m^2\n' ...
            %     '油管内油液环空横截面积 A1: %.4f m^2\n' ...
            %     '套管内油液环空横截面积 A2: %.4f m^2\n' ...
            %     '螺杆泵横截面积 A3: %.4f m^2\n' ...
            %     '油套液面高度差 DeltaH: %.4f m\n'
            %     ], D1i, D2i, D3i, A1, A2, A3, DeltaH);
            % 
            % % 使用 msgbox 显示所有结果
            % msgbox(results, '计算结果');


            g = 9.81; % 重力加速度，单位 m/s^2
            DeltaH = H1 - H2; % 油套液面高度差

            % 计算平衡时的液面高度
            H = (A1 * H1 + A2 * H2) / (A1 + A2);

            % 计算油液平衡时的时间
            t = sqrt(2 / g) * (1 / A3) * sqrt(A1 * A2 / (A1 + A2)) * sqrt(DeltaH);

            results2 = sprintf(['平衡液面高度 H:%.4f m\n'...
                               '油液平衡耗时 t:%.4f s\n'],H,t);
            msgbox(results2,'计算结果2')


        end

        % Button pushed function: Button_2
        function ButtonPused2(app, event)

            Q = str2double(app.TextArea_11.Value); % 日产量
            n = str2double(app.TextArea_12.Value); % 抽油杆转速
            dP = str2double(app.TextArea_13.Value);% 油套压差
            delta0 = str2double(app.TextArea_14.Value); % 定转子初始过盈量
            tau =  str2double(app.TextArea_15.Value);   % 油液粘度 
            L =  str2double(app.TextArea_16.Value);     % 抽油杆长度
            app.L =L;
            m = str2double(app.TextArea_17.Value); % 制动扭矩倍数
            D2i = app.D2i;  % 油管内径 
            D1o = app.D1o;  % 抽油杆外径
            
            % 计算抽油杆转速 (w)
            w = n * (2 * pi / 60); % 将转速从 r/min 转换为 rad/s

            % 计算螺杆泵举升液体的有功扭矩 (Mp)
            Mp = 110.5 * (Q / n) * dP;

            % 计算螺杆泵定转子摩擦扭矩 (Mb)
            Mb = 91.3 * delta0 - n^0.45 + 46.5;

            % 计算油液对抽油杆的摩擦扭矩 (Mr)
            % 注意：这里需要 D2i 和 D1o 的值，它们应该从之前的计算中获得
            % 假设 D2i 和 D1o 已经计算好并存储在相应变量中
            Mr = (pi * tau * w * D2i^2 * D1o^2 * L) / (D2i^2 - D1o^2) * 10^(-2);

            % 计算总负载扭矩(Ma)
            Ma = Mp + Mb + Mr; 

            % 调用msgbox输出单个变量值得
            %msgbox(['总负载扭矩 Ma: ', num2str(Ma), ' N·m'], '计算结果');


            %输入参数
            miu=tau;
            m=[0.1:0.1:1.4]';
            m= str2double(app.TextArea_17.Value); 
            Mt=Ma*m;
            app.Mt =Mt;
            tt3=ZhiDongShiJian(Ma,L,miu,m); % 求解制动扭矩
            vv3=FanZhuanSuDu(Ma,L,miu,m);   % 求解反转速度

            results3 = sprintf(['制动扭矩倍数 m:%.4f \n'...
                               '制动扭矩 Mt:%.4f N·M\n'...
                               '反转时间 tt3:%.4f s\n...' ...
                               '反转速度 vv3:%.4f rad\s'],m,Mt,tt3,vv3);
            msgbox(results3,'计算结果3')


            % 反转时间拟合值筛选器
            function tt3=ZhiDongShiJian(Ma,L,miu,m)
                %730mPas的制动扭矩与制动时间
                t1=1.84132*exp(-m/0.16103)+0.17304;       %500m
                t2=3.73*exp(-m/0.14702)+0.34622;       %1000m
                t3=5.64339*exp(-m/0.14644)+0.50965;       %1500m

                %73mPas的制动扭矩与制动时间
                t4=2.90962*exp(-m/0.35734)-0.00789;       %500m
                t5=5.46402*exp(-m/0.2298)+0.28763;       %1000m
                t6=6.76939*exp(-m/0.20879)+0.48239;       %1500m

                %7.3mPas的制动扭矩与制动时间
                t7=4.33981*exp(-m/0.23498)+0.30764;       %500m
                t8=8.19774*exp(-m/0.23737)+0.4909;       %1000m
                t9=12.42276*exp(-m/0.25009)+0.61157;       %1500m

                if miu<=7.3
                    if L<=500
                        tt3=t7;
                    elseif L<=1000
                        tt3=t7+abs(t8-t7)*(L-500)/(1000-500);
                    elseif L<=1500
                        tt3=t8+abs(t9-t8)*(L-1000)/(1500-1000);
                    else
                        tt3=t9+t9*(L-1500)/1500;
                    end
                end


                if miu>7.3&&miu<=73
                    if L<=500
                        tt1=t7;
                        tt2=t4;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    elseif L<=1000
                        tt1=min(t7,t8)+abs(t7-t8)*(L-500)/(1000-500);
                        tt2=min(t4,t5)+abs(t4-t5)*(L-500)/(1000-500);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    elseif L<=1500
                        tt1=min(t8,t9)+abs(t8-t9)*(L-1000)/(1500-1000);
                        tt2=min(t5,t6)+abs(t5-t6)*(L-1000)/(1500-1000);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    else
                        tt1=t9+t9*(L-1500)/1500;
                        tt2=t6+t6*(L-1500)/1500;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    end
                end


                if miu>73&&miu<730
                    if L<=500
                        tt1=t4;
                        tt2=t1;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    elseif L<=1000
                        tt1=min(t7,t8)+abs(t7-t8)*(L-500)/(1000-500);
                        tt2=min(t4,t5)+abs(t4-t5)*(L-500)/(1000-500);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    elseif L<=1500
                        tt1=min(t8,t9)+abs(t8-t9)*(L-1000)/(1500-1000);
                        tt2=min(t5,t6)+abs(t5-t6)*(L-1000)/(1500-1000);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    else
                        tt1=t6+t6*(L-1500)/1500;
                        tt2=t3+t3*(L-1500)/1500;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    end
                end


                if miu>=730
                    if L<=500
                        tt3=t1;
                    elseif L<=1000
                        tt3=t1+abs(t1-t2)*(L-500)/(1000-500);
                    elseif L<=1500
                        tt3=t2+abs(t2-t3)*(L-1000)/(1500-1000);
                    else
                        tt3=t3+t3*(L-1500)/1500;
                    end
                end
            end

            % 反转速度公式筛选器

            function vv3=FanZhuanSuDu(Ma,L,miu,m)

                %反转速度公式表
                %730mPas的制动扭矩与制动时间
                v1=27.60817*exp(-m/0.75485)-3.76247;       %500m
                v2=19.26888*exp(-m/0.71987)-2.12881;       %1000m
                v3=11.7411*exp(-m/0.72749)-1.3438;       %1500m

                %73mPas的制动扭矩与制动时间
                v4=6.92249*exp(-m/0.4918)-0.01399;       %500m
                v5=6.43102*exp(-m/0.4993)-0.11799;       %1000m
                v6=6.75866*exp(-m/0.59508)-0.39038;       %1500m

                %7.3mPas的制动扭矩与制动时间
                v7=7.9066*exp(-m/0.4513)-0.32138;       %500m
                v8=7.62441*exp(-m/0.44169)-0.24903;       %1000m
                v9=7.37815*exp(-m/0.43257)-0.18191;       %1500m
                

                % 反转速度筛选器
                if miu<=7.3
                    if L<=500
                        vv3=v7;
                    elseif L<=1000
                        vv3=v7+abs(v8-v7)*(L-500)/(1000-500);
                    elseif L<=1500
                        vv3=v8+abs(v9-v8)*(L-1000)/(1500-1000);
                    else
                        vv3=v9+v9*(L-1500)/1500;
                    end
                end


                if miu>7.3&&miu<=73
                    if L<=500
                        vv1=v7;
                        vv2=v4;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    elseif L<=1000
                        vv1=min(v7,v8)+abs(v7-v8)*(L-500)/(1000-500);
                        vv2=min(v4,v5)+abs(v4-v5)*(L-500)/(1000-500);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    elseif L<=1500
                        vv1=min(v8,v9)+abs(v8-v9)*(L-1000)/(1500-1000);
                        vv2=min(v5,v6)+abs(v5-v6)*(L-1000)/(1500-1000);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    else
                        vv1=v9+v9*(L-1500)/1500;
                        vv2=v6+v6*(L-1500)/1500;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    end
                end


                if miu>73&&miu<730
                    if L<=500
                        vv1=v4;
                        vv2=v1;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    elseif L<=1000
                        vv1=min(v7,v8)+abs(v7-v8)*(L-500)/(1000-500);
                        vv2=min(v4,v5)+abs(v4-v5)*(L-500)/(1000-500);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    elseif L<=1500
                        vv1=min(v8,v9)+abs(v8-v9)*(L-1000)/(1500-1000);
                        vv2=min(v5,v6)+abs(v5-v6)*(L-1000)/(1500-1000);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    else
                        vv1=v6+v6*(L-1500)/1500;
                        vv2=v3+v3*(L-1500)/1500;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    end
                end


                if miu>=730
                    if L<=500
                        vv=v1;
                    elseif L<=1000
                        vv3=v1+abs(v1-v2)*(L-500)/(1000-500);
                    elseif L<=1500
                        vv3=v2+abs(v2-v3)*(L-1000)/(1500-1000);
                    else
                        vv3=v3+v3*(L-1500)/1500;
                    end
                end
            end


        end

        % Button pushed function: Button_3
        function ButtonPushed3(app, event)
            %%%% 计算液压式制动模型 %%%%
            % 接收参数u                             % 摩擦系数
            % 接收参数vv3                           % 制动力矩
            % 接收参数Py                            % 制动时间
            % 接收参数tt3                           % 摩擦片压力
            % 输出参数TJEMAX                        % 制动杆转速

            u = str2double(app.TextArea_18.Value); % 摩擦系数
            Mt = app.Mt;                           % 制动力矩
            tt3 = app.tt3;                         % 制动时间
            
            vv3 = app.vv3;                         % 制动杆转速
                                                   
            %% 验证参数
            % u = 0.30;
            % vv3 = 10;
            % Py =0.279;
            % tt3 = 0.785;
            % Mt = 469;
            
            %%% 第一步：计算摩擦片压力Fy %%%
            % 接收参数Mt
            % 接收参数u
            % 产生参数Py

            Py = Mt/5096/u;                        % 摩擦片压力

            
            %%% 第二步：计算液压式式制动温度 %%%
            % 接收参数u
            % 接收参数vv3
            % 接收参数Fy
            % 接收参数tt3
            % 输出参数TJEMAX

            temp_matrix = [1.891,1.020,0.1;...     % 创建拟合变量参数阵列
                           5.402,0.869,0.15;...
                           7.556,0.775,0.2;...
                           9.991,0.840,0.25;...
                           12.603,0.826,0.3;...
                           14.286,0.796,0.35;...
                           16.884,0.787,0.4];

           
            % 创建表格
            temp_table = array2table(temp_matrix, 'VariableNames', {'Column1', 'Column2', 'Column3'});

            % 查找第三列中是否包含 u
            if any(ismember(u, temp_table.Column3))  % 使用 any 检查是否有匹配
                % 获取包含 u 的行的索引
                row_index = find(temp_table.Column3 == u);

                % 获取对应的行
                matching_row = temp_table(row_index, :);

                % 假设你想获取第一行（如果存在多行，这里需要根据实际情况调整）
                a = matching_row.Column1(1);
                b = matching_row.Column2(1);

                % 计算 TEMAX
                TEMAX = vv3 * tt3/(2*pi) * a * Py^b + 20;
            else
                % 如果没有找到，执行其他操作
                index_down = temp_table.Column3 > u;
                up_rows = temp_table(index_down, :);  % 计算大于u得到的参数表，取最上一行参数

                index_up = temp_table.Column3 < u;
                down_rows = temp_table(index_up, :);  % 计算小于u得到的参数表，取最下一行参数

                a1= down_rows.Column1(end); % Notice:直接使用括号索引返回的是cell
                b1= down_rows.Column2(end); % Notice:使用点号+列名索引返回的才是值

                a2 = up_rows.Column1(1);
                b2 = up_rows.Column2(1);

                TEMX1 = vv3 * tt3/(2*pi) * a1 * Py^ b1+ 20;
                TEMX2 = vv3 * tt3/(2*pi) *a2  * Py^b2 + 20;

                ua = down_rows.Column3(end);  % 得到与 u 最近且小于 u 的摩擦系数
                TEMAX = (u - ua)/0.05 * (TEMX2 - TEMX1) + TEMX1; % 插值计算
            end
            %%%% 计算机械式制动模型 %%%%

            %% 验证参数
            % u = 0.30;
            % vv3 = 10;
            % Py =0.279;
            % tt3 = 0.785;
            % Mt = 469;

            %%% 第一步：计算摩擦片压力Fy %%%
            % 接收参数Mt
            % 接收参数u
            % 产生参数Fy

            Fy = Mt/0.68254/(u^1.1853);

            %%% 第二步：计算机械式制动温度 %%%
            % 接收参数u
            % 接收参数vv3
            % 接收参数Fy
            % 接收参数tt3
            % 输出参数TJEMAX

            %% 验证参数
            %u =0.33;
            %vv3 = 10;
            %Fy = 2557;
            %tt3 =0.785;


            Fn = Fy/2687;        % 计算作用在摩擦片压力Fn
            J = Fn*vv3*tt3;      % 计算摩擦热能J

            format long;
            temp_matrix2 = [     % 创建液压式拟合参数表
                0.54995,-0.00146,0.3;
                0.7518,-0.00396,0.35;
                0.85598,-0.00314,0.4];

            temp_table2 = array2table(temp_matrix2, 'VariableNames', {'Column1', 'Column2', 'Column3'});
            if any(ismember(u, temp_table2.Column3))
                % 获取包含 u 的行的索引
                row_index2 = find(temp_table2.Column3 == u);

                % 获取对应的行
                matching_row2 = temp_table2(row_index2, :);

                % 假设你想获取第一行（如果存在多行，这里需要根据实际情况调整）
                a1 = matching_row2.Column1(1);
                b1 = matching_row2.Column2(1);

                % 计算 TEMAX
                TJEMAX = 20+a1*J+b1*J*J;
            else
                % 如果没有找到，执行其他操作
                index_down2 = temp_table2.Column3 > u;
                up_rows2 = temp_table2(index_down2, :);  % 计算大于u得到的参数表，取最上一行参数

                index_up2 = temp_table2.Column3 < u;
                down_rows2 = temp_table2(index_up2, :);  % 计算小于u得到的参数表，取最下一行参数

                aj1= down_rows2.Column1(end); % Notice:直接使用括号索引返回的是cell
                bj1= down_rows2.Column2(end); % Notice:使用点号+列名索引返回的才是值

                aj2 = up_rows2.Column1(1);
                bj2 = up_rows2.Column2(1);

                TJEMX1 = 20+aj1*J+bj1*J*J;
                TJEMX2 = 20+aj2*J+bj2*J*J;

                uja = up_rows2.Column3(1);  % 得到与 u 最近且小于 u 的摩擦系数
                TJEMAX = (u - uja)/0.05 * (TJEMX2 - TJEMX1) + TJEMX1; % 插值计算

            end
            results3 = sprintf(['液压式摩擦预紧力:%.4f \n'...
                '液压式制动温度:%.4f \n'...
                '机械式制动摩擦预紧力:%.4f Mpa\n'...
                '机械式制动温度：%.4f\n'...
                ],Py,TEMAX,Fy,TJEMAX);
            msgbox(results3,'计算结果3')
        end

        % Button pushed function: Button_4
        function ButtonPushed4(app, event)
            %%%% 能耗式模型计算 %%%%
            
            D1o =app.D1o;   % 获取抽油杆外径
            th1 = app.th1;  % 获取抽油杆壁厚
            L = app.L;      % 获取抽油杆长度
            Dens = str2double(app.TextArea_22.Value);
            Ex1 = str2double(app.TextArea_23.Value);
            miu1 = str2double(app.TextArea_24.Value);

            % Notice：在把程序放入GUI中前先进行局部验证，防止直接放入GUI中出现报错难以查找，增加开发时间
            % Notice: 将所有用到的输入变量分类汇总，方便Debug
            % Notice: 使用变量符号保持与论文同步，减少校准工作量

            % 总传入参数统计


            % 固定参数 Ke          电动势常数
            % 固定参数 kr          转矩常数

            % 从变量池中获取参数
            % 接收参数 D1o         抽油杆外径
            % 接收参数 th1         抽油杆壁厚
            % 接收参数 L           抽油杆长度

            % 新输入参数
            % 接收参数 Dens        抽油杆密度
            % 接收参数 Ex1         抽油杆弹性模量
            % 接收参数 miu1        泊松比

            % 过程弹窗输入参数
            % 接收参数R            实际电阻


            %% 1.计算模态角速度 %%
            % 接收参数 D1o         抽油杆外径
            % 接收参数 th1         抽油杆壁厚
            % 接收参数 L           抽油杆长度
            % 接收参数 Dens        抽油杆密度
            % 接收参数 Ex1         抽油杆弹性模量
            % 接收参数 miu1        泊松比
            % 输出参数 ww1         模态角速度 S1

            %验证参数
            % D1o = 38e-3;
            % th1 = 6e-3;
            % L = 1300;
            % Dens = 7800;
            % Ex1 = 210e9;
            % miu1 = 0.3;


            D1i = D1o -2*th1;            % 抽油杆内径
            G1 = Ex1*0.5/(1+miu1);       % 抽油杆剪切模量
            I_p1=pi/32*(D1o^4-D1i^4);    % 抽油杆极惯性距

            ww1 = 1/(4*L)*sqrt(G1/Dens)*2*pi;


            %% 2.求临界电阻 %%
            % 固定参数 Ke          电动势常数
            % 固定参数 kr          转矩常数
            Ke = 3.33;
            kr = 3.41;

            % 接收参数 D1o         抽油杆外径
            % 接收参数 th1         抽油杆壁厚
            % 接收参数 Dens        抽油杆密度
            % 接收参数 ww1         模态角速度 E1
            % 输出参数 Rl              临界电阻 S2

            %验证参数
            % D1o = 38e-3;
            % th1 = 6e-3;
            % Dens = 7800;
            % ww1 = 3.882;

            D1i = D1o -2*th1;               % 抽油杆内径
            I_p1=pi/32*(D1o^4-D1i^4);       % 抽油杆极惯性距

            Rl = Ke*kr/(Dens*I_p1)/(2*ww1); % 临界电阻 E2


            %% 3.求制动时间与制动力矩 %%
            % 接收参数 D1o         抽油杆外径
            % 接收参数 th1         抽油杆壁厚
            % 接收参数 Dens        抽油杆密度
            % 接收参数 Ke          电动势常数
            % 接收参数 kr          转矩常数
            % 接收参数 ww1         模态角速度
            % 接收参数 R           实际电阻
            % 输出参数 tz          制动时间
            % 输出参数 Te          电磁转矩最大值

            %验证参数
            % D1o = 38e-3;
            % th1 = 6e-3;
            % Dens = 7800;
            % Ke = 3.33;
            % kr =3.41;
            % ww1 = 3.8882;
             R = 1500;


            % 定义符号变量和常数

            c_e = Ke;                  %电动势常数
            c_T = kr;                  % 转矩常数
            a = 30e-3;                 % 常量
            b = 266e-3;                % 常量
            R = R;                     %  甲方输入电阻，Ω
            rou = Dens;                % 抽油杆密度,
            D1_out = D1o;              % 抽油杆外径, m
            t1 = th1;                  % 抽油杆壁厚, m
            D1_in = D1_out - 2 * t1;   % 抽油杆内径, m
            pi_val = pi;               % 角度 π
            W1 = ww1;                  %模态角速度，rad/s


            % Notice:在引用公共函数时第一个参数得是app对象
            [max_abs_Te,max_time,result_powered,t_min]=NengHaoZhiDong(app,c_e,c_T,a,b,R,rou,D1_out,D1_in,pi_val,W1);


            disp(['电磁转矩 Te 的绝对值最大值为: ', num2str(max_abs_Te), ' N?m']);
            disp(['发生在时间: ', num2str(max_time), ' s']);
            disp(['电阻温度为: ', num2str(result_powered), ' ℃']);

            %Notice：sprintf的输入缓冲区变量类型要匹配，此处使用了num2str导致输出了弹窗数组消息
            results4 = sprintf(['电磁转矩 Te 的绝对值最大值为:%.4f \n'...
                '发生在时间:%.4f\n'...
                '电阻温度为::%.4f\n'...
                '制动时间:%.4f\n'
                ],max_abs_Te,max_time,result_powered,t_min);
            msgbox(results4,'计算结果3')
            
%明日任务：
%1.实现弹窗输入R值
%2.参数单位补齐
%3.GUI布局重新设计


        end

        % Callback function
        function Button_10Pushed(app, event)
                
            
            % 直接从编辑框控件获取值
            % 从app实例的TextArea_10标识符的Value属性中提取值
            
            % 读取用户输入的值
            D1o = str2double(app.TextArea.Value); % 抽油杆外径
            th1 = str2double(app.TextArea_2.Value); % 抽油杆壁厚
            D2o = str2double(app.TextArea_3.Value); % 油管外径
            th2 = str2double(app.TextArea_4.Value); % 油管壁厚
            D3o = str2double(app.TextArea_5.Value); % 套管外径
            th3 = str2double(app.TextArea_6.Value); % 套管壁厚
            H1 = str2double(app.TextArea_7.Value); % 油管内液面高度
            H2 = str2double(app.TextArea_8.Value); % 套管内液面高度
            e = str2double(app.TextArea_9.Value); % 螺杆泵偏心距
            D = str2double(app.TextArea_10.Value); % 螺杆泵截面直径


            % 计算内径
            D1i = D1o - 2 * th1; % 抽油杆内径
            D2i = D2o - 2 * th2; % 油管内径
            D3i = D3o - 2 * th3; % 套管内径
            
            app.D2i = D2i; % 将计算值存储为app属性
            app.D1o = D1o; % 将计算值存储为app属性方便其他功能组件调用
            app.th1 = th1; % 将壁厚值存储为app公共变量池中


            % 计算横截面积
            A1 = (pi/4) * (D2i^2 - D1o^2); % 油管内油液环空横截面积
            A2 = (pi/4) * (D3i^2 - D2o^2); % 套管内油液环空横截面积
            A3 = 4 * e * D; % 螺杆泵横截面积

            % 计算液面高度差
            DeltaH = H1 - H2;

            
            % % 创建一个字符串来存储所有的结果
            % results = sprintf([
            %     '抽油杆内径 D1i: %.4f m^2\n' ...
            %     '油管内径 D2i: %.4f m^2\n' ...
            %     '套管内径 D3i: %.4f m^2\n' ...
            %     '油管内油液环空横截面积 A1: %.4f m^2\n' ...
            %     '套管内油液环空横截面积 A2: %.4f m^2\n' ...
            %     '螺杆泵横截面积 A3: %.4f m^2\n' ...
            %     '油套液面高度差 DeltaH: %.4f m\n'
            %     ], D1i, D2i, D3i, A1, A2, A3, DeltaH);
            % 
            % % 使用 msgbox 显示所有结果
            % msgbox(results, '计算结果');


            g = 9.81; % 重力加速度，单位 m/s^2
            DeltaH = H1 - H2; % 油套液面高度差

            % 计算平衡时的液面高度
            H = (A1 * H1 + A2 * H2) / (A1 + A2);

            % 计算油液平衡时的时间
            t = sqrt(2 / g) * (1 / A3) * sqrt(A1 * A2 / (A1 + A2)) * sqrt(DeltaH);

            results2 = sprintf(['平衡液面高度 H:%.4f m\n'...
                               '油液平衡耗时 t:%.4f s\n'],H,t);
            msgbox(results2,'计算结果2')


                  Q = str2double(app.TextArea_11.Value); % 日产量
            n = str2double(app.TextArea_12.Value); % 抽油杆转速
            dP = str2double(app.TextArea_13.Value);% 油套压差
            delta0 = str2double(app.TextArea_14.Value); % 定转子初始过盈量
            tau =  str2double(app.TextArea_15.Value);   % 油液粘度 
            L =  str2double(app.TextArea_16.Value);     % 抽油杆长度
            app.L =L;
            m = str2double(app.TextArea_17.Value); % 制动扭矩倍数
            D2i = app.D2i;  % 油管内径 
            D1o = app.D1o;  % 抽油杆外径
            
            % 计算抽油杆转速 (w)
            w = n * (2 * pi / 60); % 将转速从 r/min 转换为 rad/s

            % 计算螺杆泵举升液体的有功扭矩 (Mp)
            Mp = 110.5 * (Q / n) * dP;

            % 计算螺杆泵定转子摩擦扭矩 (Mb)
            Mb = 91.3 * delta0 - n^0.45 + 46.5;

            % 计算油液对抽油杆的摩擦扭矩 (Mr)
            % 注意：这里需要 D2i 和 D1o 的值，它们应该从之前的计算中获得
            % 假设 D2i 和 D1o 已经计算好并存储在相应变量中
            Mr = (pi * tau * w * D2i^2 * D1o^2 * L) / (D2i^2 - D1o^2) * 10^(-2);

            % 计算总负载扭矩(Ma)
            Ma = Mp + Mb + Mr; 

            % 调用msgbox输出单个变量值得
            %msgbox(['总负载扭矩 Ma: ', num2str(Ma), ' N·m'], '计算结果');


            %输入参数
            miu=tau;
            m=[0.1:0.1:1.4]';
            m= str2double(app.TextArea_17.Value); 
            Mt=Ma*m;
            app.Mt =Mt;
            tt3=ZhiDongShiJian(Ma,L,miu,m); % 求解制动扭矩
            vv3=FanZhuanSuDu(Ma,L,miu,m);   % 求解反转速度

            results3 = sprintf(['制动扭矩倍数 m:%.4f \n'...
                               '制动扭矩 Mt:%.4f N·M\n'...
                               '反转时间 tt3:%.4f s\n...' ...
                               '反转速度 vv3:%.4f rad\s'],m,Mt,tt3,vv3);
            msgbox(results3,'计算结果3')


            % 反转时间拟合值筛选器
            function tt3=ZhiDongShiJian(Ma,L,miu,m)
                %730mPas的制动扭矩与制动时间
                t1=1.84132*exp(-m/0.16103)+0.17304;       %500m
                t2=3.73*exp(-m/0.14702)+0.34622;       %1000m
                t3=5.64339*exp(-m/0.14644)+0.50965;       %1500m

                %73mPas的制动扭矩与制动时间
                t4=2.90962*exp(-m/0.35734)-0.00789;       %500m
                t5=5.46402*exp(-m/0.2298)+0.28763;       %1000m
                t6=6.76939*exp(-m/0.20879)+0.48239;       %1500m

                %7.3mPas的制动扭矩与制动时间
                t7=4.33981*exp(-m/0.23498)+0.30764;       %500m
                t8=8.19774*exp(-m/0.23737)+0.4909;       %1000m
                t9=12.42276*exp(-m/0.25009)+0.61157;       %1500m

                if miu<=7.3
                    if L<=500
                        tt3=t7;
                    elseif L<=1000
                        tt3=t7+abs(t8-t7)*(L-500)/(1000-500);
                    elseif L<=1500
                        tt3=t8+abs(t9-t8)*(L-1000)/(1500-1000);
                    else
                        tt3=t9+t9*(L-1500)/1500;
                    end
                end


                if miu>7.3&&miu<=73
                    if L<=500
                        tt1=t7;
                        tt2=t4;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    elseif L<=1000
                        tt1=min(t7,t8)+abs(t7-t8)*(L-500)/(1000-500);
                        tt2=min(t4,t5)+abs(t4-t5)*(L-500)/(1000-500);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    elseif L<=1500
                        tt1=min(t8,t9)+abs(t8-t9)*(L-1000)/(1500-1000);
                        tt2=min(t5,t6)+abs(t5-t6)*(L-1000)/(1500-1000);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    else
                        tt1=t9+t9*(L-1500)/1500;
                        tt2=t6+t6*(L-1500)/1500;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    end
                end


                if miu>73&&miu<730
                    if L<=500
                        tt1=t4;
                        tt2=t1;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    elseif L<=1000
                        tt1=min(t7,t8)+abs(t7-t8)*(L-500)/(1000-500);
                        tt2=min(t4,t5)+abs(t4-t5)*(L-500)/(1000-500);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    elseif L<=1500
                        tt1=min(t8,t9)+abs(t8-t9)*(L-1000)/(1500-1000);
                        tt2=min(t5,t6)+abs(t5-t6)*(L-1000)/(1500-1000);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    else
                        tt1=t6+t6*(L-1500)/1500;
                        tt2=t3+t3*(L-1500)/1500;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    end
                end


                if miu>=730
                    if L<=500
                        tt3=t1;
                    elseif L<=1000
                        tt3=t1+abs(t1-t2)*(L-500)/(1000-500);
                    elseif L<=1500
                        tt3=t2+abs(t2-t3)*(L-1000)/(1500-1000);
                    else
                        tt3=t3+t3*(L-1500)/1500;
                    end
                end
            end

            % 反转速度公式筛选器

            function vv3=FanZhuanSuDu(Ma,L,miu,m)

                %反转速度公式表
                %730mPas的制动扭矩与制动时间
                v1=27.60817*exp(-m/0.75485)-3.76247;       %500m
                v2=19.26888*exp(-m/0.71987)-2.12881;       %1000m
                v3=11.7411*exp(-m/0.72749)-1.3438;       %1500m

                %73mPas的制动扭矩与制动时间
                v4=6.92249*exp(-m/0.4918)-0.01399;       %500m
                v5=6.43102*exp(-m/0.4993)-0.11799;       %1000m
                v6=6.75866*exp(-m/0.59508)-0.39038;       %1500m

                %7.3mPas的制动扭矩与制动时间
                v7=7.9066*exp(-m/0.4513)-0.32138;       %500m
                v8=7.62441*exp(-m/0.44169)-0.24903;       %1000m
                v9=7.37815*exp(-m/0.43257)-0.18191;       %1500m
                

                % 反转速度筛选器
                if miu<=7.3
                    if L<=500
                        vv3=v7;
                    elseif L<=1000
                        vv3=v7+abs(v8-v7)*(L-500)/(1000-500);
                    elseif L<=1500
                        vv3=v8+abs(v9-v8)*(L-1000)/(1500-1000);
                    else
                        vv3=v9+v9*(L-1500)/1500;
                    end
                end


                if miu>7.3&&miu<=73
                    if L<=500
                        vv1=v7;
                        vv2=v4;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    elseif L<=1000
                        vv1=min(v7,v8)+abs(v7-v8)*(L-500)/(1000-500);
                        vv2=min(v4,v5)+abs(v4-v5)*(L-500)/(1000-500);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    elseif L<=1500
                        vv1=min(v8,v9)+abs(v8-v9)*(L-1000)/(1500-1000);
                        vv2=min(v5,v6)+abs(v5-v6)*(L-1000)/(1500-1000);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    else
                        vv1=v9+v9*(L-1500)/1500;
                        vv2=v6+v6*(L-1500)/1500;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    end
                end


                if miu>73&&miu<730
                    if L<=500
                        vv1=v4;
                        vv2=v1;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    elseif L<=1000
                        vv1=min(v7,v8)+abs(v7-v8)*(L-500)/(1000-500);
                        vv2=min(v4,v5)+abs(v4-v5)*(L-500)/(1000-500);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    elseif L<=1500
                        vv1=min(v8,v9)+abs(v8-v9)*(L-1000)/(1500-1000);
                        vv2=min(v5,v6)+abs(v5-v6)*(L-1000)/(1500-1000);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    else
                        vv1=v6+v6*(L-1500)/1500;
                        vv2=v3+v3*(L-1500)/1500;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    end
                end


                if miu>=730
                    if L<=500
                        vv=v1;
                    elseif L<=1000
                        vv3=v1+abs(v1-v2)*(L-500)/(1000-500);
                    elseif L<=1500
                        vv3=v2+abs(v2-v3)*(L-1000)/(1500-1000);
                    else
                        vv3=v3+v3*(L-1500)/1500;
                    end
                end
            end

               %%%% 计算液压式制动模型 %%%%
            % 接收参数u                             % 摩擦系数
            % 接收参数vv3                           % 制动力矩
            % 接收参数Py                            % 制动时间
            % 接收参数tt3                           % 摩擦片压力
            % 输出参数TJEMAX                        % 制动杆转速

            u = str2double(app.TextArea_18.Value); % 摩擦系数
            Mt = app.Mt;                           % 制动力矩
            tt3 = app.tt3;                         % 制动时间
            
            vv3 = app.vv3;                         % 制动杆转速
                                                   
            %% 验证参数
            % u = 0.30;
            % vv3 = 10;
            % Py =0.279;
            % tt3 = 0.785;
            % Mt = 469;
            
            %%% 第一步：计算摩擦片压力Fy %%%
            % 接收参数Mt
            % 接收参数u
            % 产生参数Py

            Py = Mt/5096/u;                        % 摩擦片压力

            
            %%% 第二步：计算液压式式制动温度 %%%
            % 接收参数u
            % 接收参数vv3
            % 接收参数Fy
            % 接收参数tt3
            % 输出参数TJEMAX

            temp_matrix = [1.891,1.020,0.1;...     % 创建拟合变量参数阵列
                           5.402,0.869,0.15;...
                           7.556,0.775,0.2;...
                           9.991,0.840,0.25;...
                           12.603,0.826,0.3;...
                           14.286,0.796,0.35;...
                           16.884,0.787,0.4];

           
            % 创建表格
            temp_table = array2table(temp_matrix, 'VariableNames', {'Column1', 'Column2', 'Column3'});

            % 查找第三列中是否包含 u
            if any(ismember(u, temp_table.Column3))  % 使用 any 检查是否有匹配
                % 获取包含 u 的行的索引
                row_index = find(temp_table.Column3 == u);

                % 获取对应的行
                matching_row = temp_table(row_index, :);

                % 假设你想获取第一行（如果存在多行，这里需要根据实际情况调整）
                a = matching_row.Column1(1);
                b = matching_row.Column2(1);

                % 计算 TEMAX
                TEMAX = vv3 * tt3/(2*pi) * a * Py^b + 20;
            else
                % 如果没有找到，执行其他操作
                index_down = temp_table.Column3 > u;
                up_rows = temp_table(index_down, :);  % 计算大于u得到的参数表，取最上一行参数

                index_up = temp_table.Column3 < u;
                down_rows = temp_table(index_up, :);  % 计算小于u得到的参数表，取最下一行参数

                a1= down_rows.Column1(end); % Notice:直接使用括号索引返回的是cell
                b1= down_rows.Column2(end); % Notice:使用点号+列名索引返回的才是值

                a2 = up_rows.Column1(1);
                b2 = up_rows.Column2(1);

                TEMX1 = vv3 * tt3/(2*pi) * a1 * Py^ b1+ 20;
                TEMX2 = vv3 * tt3/(2*pi) *a2  * Py^b2 + 20;

                ua = down_rows.Column3(end);  % 得到与 u 最近且小于 u 的摩擦系数
                TEMAX = (u - ua)/0.05 * (TEMX2 - TEMX1) + TEMX1; % 插值计算
            end
            %%%% 计算机械式制动模型 %%%%

            %% 验证参数
            % u = 0.30;
            % vv3 = 10;
            % Py =0.279;
            % tt3 = 0.785;
            % Mt = 469;

            %%% 第一步：计算摩擦片压力Fy %%%
            % 接收参数Mt
            % 接收参数u
            % 产生参数Fy

            Fy = Mt/0.68254/(u^1.1853);

            %%% 第二步：计算机械式制动温度 %%%
            % 接收参数u
            % 接收参数vv3
            % 接收参数Fy
            % 接收参数tt3
            % 输出参数TJEMAX

            %% 验证参数
            %u =0.33;
            %vv3 = 10;
            %Fy = 2557;
            %tt3 =0.785;


            Fn = Fy/2687;        % 计算作用在摩擦片压力Fn
            J = Fn*vv3*tt3;      % 计算摩擦热能J

            format long;
            temp_matrix2 = [     % 创建液压式拟合参数表
                0.54995,-0.00146,0.3;
                0.7518,-0.00396,0.35;
                0.85598,-0.00314,0.4];

            temp_table2 = array2table(temp_matrix2, 'VariableNames', {'Column1', 'Column2', 'Column3'});
            if any(ismember(u, temp_table2.Column3))
                % 获取包含 u 的行的索引
                row_index2 = find(temp_table2.Column3 == u);

                % 获取对应的行
                matching_row2 = temp_table2(row_index2, :);

                % 假设你想获取第一行（如果存在多行，这里需要根据实际情况调整）
                a1 = matching_row2.Column1(1);
                b1 = matching_row2.Column2(1);

                % 计算 TEMAX
                TJEMAX = 20+a1*J+b1*J*J;
            else
                % 如果没有找到，执行其他操作
                index_down2 = temp_table2.Column3 > u;
                up_rows2 = temp_table2(index_down2, :);  % 计算大于u得到的参数表，取最上一行参数

                index_up2 = temp_table2.Column3 < u;
                down_rows2 = temp_table2(index_up2, :);  % 计算小于u得到的参数表，取最下一行参数

                aj1= down_rows2.Column1(end); % Notice:直接使用括号索引返回的是cell
                bj1= down_rows2.Column2(end); % Notice:使用点号+列名索引返回的才是值

                aj2 = up_rows2.Column1(1);
                bj2 = up_rows2.Column2(1);

                TJEMX1 = 20+aj1*J+bj1*J*J;
                TJEMX2 = 20+aj2*J+bj2*J*J;

                uja = up_rows2.Column3(1);  % 得到与 u 最近且小于 u 的摩擦系数
                TJEMAX = (u - uja)/0.05 * (TJEMX2 - TJEMX1) + TJEMX1; % 插值计算

            end
            results3 = sprintf(['液压式摩擦预紧力:%.4f \n'...
                '液压式制动温度:%.4f\n'...
                '机械式制动摩擦预紧力:%.4f\n'...
                '机械式制动温度：%.4f\n'...
                ],Py,TEMAX,Fy,TJEMAX);
            msgbox(results3,'计算结果3')


            %%%% 能耗式模型计算 %%%%
            
            D1o =app.D1o;   % 获取抽油杆外径
            th1 = app.th1;  % 获取抽油杆壁厚
            L = app.L;      % 获取抽油杆长度
            Dens = str2double(app.TextArea_22.Value);
            Ex1 = str2double(app.TextArea_23.Value);
            miu1 = str2double(app.TextArea_24.Value);

            % Notice：在把程序放入GUI中前先进行局部验证，防止直接放入GUI中出现报错难以查找，增加开发时间
            % Notice: 将所有用到的输入变量分类汇总，方便Debug
            % Notice: 使用变量符号保持与论文同步，减少校准工作量

            % 总传入参数统计


            % 固定参数 Ke          电动势常数
            % 固定参数 kr          转矩常数

            % 从变量池中获取参数
            % 接收参数 D1o         抽油杆外径
            % 接收参数 th1         抽油杆壁厚
            % 接收参数 L           抽油杆长度

            % 新输入参数
            % 接收参数 Dens        抽油杆密度
            % 接收参数 Ex1         抽油杆弹性模量
            % 接收参数 miu1        泊松比

            % 过程弹窗输入参数
            % 接收参数R            实际电阻


            %% 1.计算模态角速度 %%
            % 接收参数 D1o         抽油杆外径
            % 接收参数 th1         抽油杆壁厚
            % 接收参数 L           抽油杆长度
            % 接收参数 Dens        抽油杆密度
            % 接收参数 Ex1         抽油杆弹性模量
            % 接收参数 miu1        泊松比
            % 输出参数 ww1         模态角速度 S1

            %验证参数
            % D1o = 38e-3;
            % th1 = 6e-3;
            % L = 1300;
            % Dens = 7800;
            % Ex1 = 210e9;
            % miu1 = 0.3;


            D1i = D1o -2*th1;            % 抽油杆内径
            G1 = Ex1*0.5/(1+miu1);       % 抽油杆剪切模量
            I_p1=pi/32*(D1o^4-D1i^4);    % 抽油杆极惯性距

            ww1 = 1/(4*L)*sqrt(G1/Dens)*2*pi;


            %% 2.求临界电阻 %%
            % 固定参数 Ke          电动势常数
            % 固定参数 kr          转矩常数
            Ke = 3.33;
            kr = 3.41;

            % 接收参数 D1o         抽油杆外径
            % 接收参数 th1         抽油杆壁厚
            % 接收参数 Dens        抽油杆密度
            % 接收参数 ww1         模态角速度 E1
            % 输出参数 Rl              临界电阻 S2

            %验证参数
            % D1o = 38e-3;
            % th1 = 6e-3;
            % Dens = 7800;
            % ww1 = 3.882;

            D1i = D1o -2*th1;               % 抽油杆内径
            I_p1=pi/32*(D1o^4-D1i^4);       % 抽油杆极惯性距

            Rl = Ke*kr/(Dens*I_p1)/(2*ww1); % 临界电阻 E2


            %% 3.求制动时间与制动力矩 %%
            % 接收参数 D1o         抽油杆外径
            % 接收参数 th1         抽油杆壁厚
            % 接收参数 Dens        抽油杆密度
            % 接收参数 Ke          电动势常数
            % 接收参数 kr          转矩常数
            % 接收参数 ww1         模态角速度
            % 接收参数 R           实际电阻
            % 输出参数 tz          制动时间
            % 输出参数 Te          电磁转矩最大值

            %验证参数
            % D1o = 38e-3;
            % th1 = 6e-3;
            % Dens = 7800;
            % Ke = 3.33;
            % kr =3.41;
            % ww1 = 3.8882;
             R = 1500;


            % 定义符号变量和常数

            c_e = Ke;                  %电动势常数
            c_T = kr;                  % 转矩常数
            a = 30e-3;                 % 常量
            b = 266e-3;                % 常量
            R = R;                     %  甲方输入电阻，Ω
            rou = Dens;                % 抽油杆密度,
            D1_out = D1o;              % 抽油杆外径, m
            t1 = th1;                  % 抽油杆壁厚, m
            D1_in = D1_out - 2 * t1;   % 抽油杆内径, m
            pi_val = pi;               % 角度 π
            W1 = ww1;                  %模态角速度，rad/s


            % Notice:在引用公共函数时第一个参数得是app对象
            [max_abs_Te,max_time,result_powered,t_min]=NengHaoZhiDong(app,c_e,c_T,a,b,R,rou,D1_out,D1_in,pi_val,W1);


            disp(['电磁转矩 Te 的绝对值最大值为: ', num2str(max_abs_Te), ' N?m']);
            disp(['发生在时间: ', num2str(max_time), ' s']);
            disp(['电阻温度为: ', num2str(result_powered), ' ℃']);

            %Notice：sprintf的输入缓冲区变量类型要匹配，此处使用了num2str导致输出了弹窗数组消息
            results4 = sprintf(['电磁转矩 Te 的绝对值最大值为:%.4f \n'...
                '发生在时间:%.4f\n'...
                '电阻温度为::%.4f\n'...
                '制动时间:%.4f\n'
                ],max_abs_Te,max_time,result_powered,t_min);
            msgbox(results4,'计算结果3')


        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            % 直接从编辑框控件获取值
            % 从app实例的TextArea_10标识符的Value属性中提取值
            
            % 读取用户输入的值
            D1o = str2double(app.TextArea.Value); % 抽油杆外径
            th1 = str2double(app.TextArea_2.Value); % 抽油杆壁厚
            D2o = str2double(app.TextArea_3.Value); % 油管外径
            th2 = str2double(app.TextArea_4.Value); % 油管壁厚
            D3o = str2double(app.TextArea_5.Value); % 套管外径
            th3 = str2double(app.TextArea_6.Value); % 套管壁厚
            H1 = str2double(app.TextArea_7.Value); % 油管内液面高度
            H2 = str2double(app.TextArea_8.Value); % 套管内液面高度
            e = str2double(app.TextArea_9.Value); % 螺杆泵偏心距
            D = str2double(app.TextArea_10.Value); % 螺杆泵截面直径


            % 计算内径
            D1i = D1o - 2 * th1; % 抽油杆内径
            D2i = D2o - 2 * th2; % 油管内径
            D3i = D3o - 2 * th3; % 套管内径
            
            app.D2i = D2i; % 将计算值存储为app属性
            app.D1o = D1o; % 将计算值存储为app属性方便其他功能组件调用
            app.th1 = th1; % 将壁厚值存储为app公共变量池中


            % 计算横截面积
            A1 = (pi/4) * (D2i^2 - D1o^2); % 油管内油液环空横截面积
            A2 = (pi/4) * (D3i^2 - D2o^2); % 套管内油液环空横截面积
            A3 = 4 * e * D; % 螺杆泵横截面积

            % 计算液面高度差
            DeltaH = H1 - H2;

            
            % % 创建一个字符串来存储所有的结果
            % results = sprintf([
            %     '抽油杆内径 D1i: %.4f m^2\n' ...
            %     '油管内径 D2i: %.4f m^2\n' ...
            %     '套管内径 D3i: %.4f m^2\n' ...
            %     '油管内油液环空横截面积 A1: %.4f m^2\n' ...
            %     '套管内油液环空横截面积 A2: %.4f m^2\n' ...
            %     '螺杆泵横截面积 A3: %.4f m^2\n' ...
            %     '油套液面高度差 DeltaH: %.4f m\n'
            %     ], D1i, D2i, D3i, A1, A2, A3, DeltaH);
            % 
            % % 使用 msgbox 显示所有结果
            % msgbox(results, '计算结果');


            g = 9.81; % 重力加速度，单位 m/s^2
            DeltaH = H1 - H2; % 油套液面高度差

            % 计算平衡时的液面高度
            H = (A1 * H1 + A2 * H2) / (A1 + A2);

            % 计算油液平衡时的时间
            t = sqrt(2 / g) * (1 / A3) * sqrt(A1 * A2 / (A1 + A2)) * sqrt(DeltaH);


            Q = str2double(app.TextArea_11.Value); % 日产量
            n = str2double(app.TextArea_12.Value); % 抽油杆转速
            dP = str2double(app.TextArea_13.Value);% 油套压差
            delta0 = str2double(app.TextArea_14.Value); % 定转子初始过盈量
            tau =  str2double(app.TextArea_15.Value);   % 油液粘度 
            L =  str2double(app.TextArea_16.Value);     % 抽油杆长度
            app.L =L;
            m = str2double(app.TextArea_17.Value); % 制动扭矩倍数
            D2i = app.D2i;  % 油管内径 
            D1o = app.D1o;  % 抽油杆外径
            
            % 计算抽油杆转速 (w)
            w = n * (2 * pi / 60); % 将转速从 r/min 转换为 rad/s

            % 计算螺杆泵举升液体的有功扭矩 (Mp)
            Mp = 110.5 * (Q / n) * dP;

            % 计算螺杆泵定转子摩擦扭矩 (Mb)
            Mb = 91.3 * delta0 - n^0.45 + 46.5;

            % 计算油液对抽油杆的摩擦扭矩 (Mr)
            % 注意：这里需要 D2i 和 D1o 的值，它们应该从之前的计算中获得
            % 假设 D2i 和 D1o 已经计算好并存储在相应变量中
            Mr = (pi * tau * w * D2i^2 * D1o^2 * L) / (D2i^2 - D1o^2) * 10^(-2);

            % 计算总负载扭矩(Ma)
            Ma = Mp + Mb + Mr; 


            %输入参数
            
            Ma=Ma;     %总负载扭矩
            L=str2double(app.TextArea_16.Value);      %抽油杆长度
            miu=str2double(app.TextArea_25.Value);    %粘度
            m=str2double(app.TextArea_17.Value);      %制动扭矩倍数
            
            % Notice:复用变量思想

            for i=1:100
                [t]=ZhiDongShiJian(L,m);
                [v]=ZhiDongSuDu(L,m,miu);
                [M_re]=NiuJu(m,Ma);
                mm(i)=m;           %制动扭矩倍数
                Mt(i)=Ma*m;      %制动扭矩
                tt(i)=t;        %制动时间
                vv(i)=v;        %反转速度
                MM_re(i)=M_re;   %残余扭矩
                Ma=M_re;         %残余扭矩
                miu=miu*m;
                if M_re<=10
                    break
                end
            end


            tt3=sum(tt);
            vv3=vv(1);
            

            %% 弃用代码，改为下述方案
            % result = sprintf(['总制动时间 %.4f s   '...
            %     '最大反转速度为 %.4f rad/s \n'],tt3,vv3);
            % 
            % % 输出表头
            % %fprintf('制动扭矩倍数  制动扭矩(N·m)  反转时间(s)  反转速度(rad/s) 残余扭矩（N·m）\n');
            % result =sprintf([result...
            %         '\n制动扭矩倍数  制动扭矩(N·m)  反转时间(s)  反转速度(rad/s) 残余扭矩（N·m）\n'])
            % % 输出数据，使用格式化字符串
            % for i = 1:length(mm)
            %     result = sprintf([result...
            %         '%12.2d  %-14.2f  %-10.2f  %-12.2f  %-10.2f\n'],mm(i), Mt(i), tt(i), vv(i), MM_re(i))
            % 
            % end
            % % Notice：使用;拼接字符串数组时严格要求字符串常量长度一致，否则报错元素维度不匹配
            % % Notice:所谓输出字符打印的过程，就是printf翻译一段来自缓冲区的连续字符串的过程
            % msgbox(result,'求解结果');


            %% 创建一个uicontrol控件来对齐展示数据，解决msgbox数据展示不齐的问题
            % 初始化单元数组
            data = {};

            % 添加表头
            data{1, 1} = '制动扭矩倍数';
            data{1, 2} = '制动扭矩(N·m)';
            data{1, 3} = '反转时间(s)';
            data{1, 4} = '反转速度(rad/s)';
            data{1, 5} = '残余扭矩(N·m)';

            % 添加数据行
            for i = 1:length(mm)
                data{1+i, 1} = num2str(mm(i), '%.2f');
                data{1+i, 2} = num2str(Mt(i), '%.2f');
                data{1+i, 3} = num2str(tt(i), '%.2f');
                data{1+i, 4} = num2str(vv(i), '%.2f');
                data{1+i, 5} = num2str(MM_re(i), '%.2f');
            end

            % 将总制动时间和最大反转速度添加到另一个单元数组中
            data2 = {sprintf('总制动时间 %.4f s', tt3), sprintf('最大反转速度为 %.4f rad/s', vv3)};


            % 参数名称
            titles = {'制动扭矩倍数', '制动扭矩(N·m)', '反转时间(s)', '反转速度(rad/s)', '残余扭矩(N·m)'};

            % 创建图形窗口
            fig = figure('Position', [400, 300, 750, 250], 'Name', '参数显示');
            % Notice：数值参数左到右分别为窗口中心的在屏幕的x，y坐标，窗口的长宽


            %创建文本控件显示数据
            for i = 1:size(data, 1)
                for j = 1:size(data, 2)
                    txt_data = uicontrol('Style', 'text', ...
                        'Position', [70 + (j-1)*120, 170 + (i-1)*(-30), 120, 20], ... % 第2个数值的步进值最好与第三个相等
                        'FontSize', 10, ...
                        'HorizontalAlignment', 'center', ...
                        'String', data{i, j});
                end
            end

            for i = 1:2
                txt_data = uicontrol('Style', 'text', ...
                    'Position', [55 + (i-1)*200, 200, 200, 20], ... % 第2个数值的步进值最好与第三个相等
                    'FontSize', 10, ...
                    'HorizontalAlignment', 'center', ...
                    'String', data2{i});
            end


            % Notice：数值参数左到右对应着元素在窗口的x，y坐标，以及元素的长宽大小
            % Notice：此角度上常数项具有新的几何意义
            % Notice：数值之间有大小包容关系原则、不能瞎填写
            % Notice:unicontrol控件连续展示参数文本时可以手动销毁上一次展示内容也可以
            %        同窗口继续展示文本

            % Notice：(ax,by)光标，当(a>0&b>0)时表示从左到右，从下到上移动
            % 当(a>0&b<0)时表示从左到右，从上到下移动，该光标可能是读取光标也可能是放置光标
            % 研究课题：figure组件内元素的绝对摆放和相对摆放


            function [t]=ZhiDongShiJian(L,m)
                t1=5.81242*exp(-m/0.22487)+0.94096;     %500m
                t2=10.54432*exp(-m/0.21419)+1.81065;     %900m
                t3=18.28527*exp(-m/0.21007)+2.68065;     %1300m
                t4=23.62921*exp(-m/0.20535)+4.23604;     %2000m
                t5=35.01277*exp(-m/0.21332)+6.15615;     %3000m
                if L<=500
                    t=t1;
                elseif L<=900
                    t=t1+(t2-t1)/(900-500)*(L-500);
                elseif L<=1300
                    t=t2+(t3-t2)/(1300-900)*(L-900);
                elseif L<=2000
                    t=t3+(t4-t3)/(2000-1300)*(L-1300);
                elseif L<=3000
                    t=t4+(t5-t4)/(3000-2000)*(L-2000);
                else
                    t=t5;
                end
            end
            function [v]=ZhiDongSuDu(L,m,miu)

                %50mPas-500m-900m-1300m-2000m-3000m
                v11=11.2236-11.038*m;
                v12=12.3301-12.126*m;
                v13=13.4365-13.214*m;
                v14=15.3727-15.118*m;
                v15=18.1611-17.849*m;

                %100mPas-500m-900m-1300m-2000m-3000m
                v21=12.6067-12.3982*m;
                v22=14.8195-14.5746*m;
                v23=17.0324-16.751*m;
                v24=20.9049-20.5597*m;
                v25=26.4694-26.0151*m;

                %200mPas-500m-900m-1300m-2000m-3000m
                v31=15.3727-15.118*m;
                v32=19.7984-19.471*m;
                v33=24.224-23.824*m;
                v34=31.8377-30.870*m;
                v35=42.233-41.968*m;

                %400mPas-500m-900m-1300m-2000m-3000m
                v41=20.9664-20.759*m;
                v42=29.8437-29.5492*m;
                v43=38.7209-38.3388*m;
                v44=54.0977-53.2056*m;
                v45=76.3194-75.0109*m;

                %730mPas-500m-900m-1300m-2000m-3000m
                v51=30.03295-29.53743*m;
                v52=46.18677-45.42503*m;
                v53=62.34062-61.3127*m;
                v54=90.60984-89.11613*m;
                v55=130.99444-128.8353*m;

                %1000mPas-500m-900m-1300m-2000m-3000m
                v61=37.50132-36.8826*m;
                v62=59.62987-58.6466*m;
                v63=81.75842-80.4105*m;
                v64=120.4833-118.497*m;
                v65=175.8047-172.907*m;

                if miu<=50
                    if L<=500
                        v=v11;
                    elseif L<=900
                        v=v11+(v12-v11)/(900-500)*(L-500);
                    elseif L<=1300
                        v=v12+(v13-v12)/(900-1300)*(L-1300);
                    elseif L<=2000
                        v=v13+(v14-v13)/(2000-1300)*(L-1300);
                    elseif L<=3000
                        v=v14+(v15-v14)/(3000-2000)*(L-2000);
                    else
                        v=v15;
                    end
                end

                if miu>50&&miu<=100
                    if L<=500
                        v01=v11;
                        v02=v21;
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=900
                        v01=min(v11,v12)+abs(v11-v12)/(900-500)*(L-500);
                        v02=min(v21,v22)+abs(v21-v22)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=1300
                        v01=min(v12,v13)+abs(v12-v13)/(1300-900)*(L-900);
                        v02=min(v22,v23)+abs(v22-v23)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=2000
                        v01=min(v13,v14)+abs(v13-v14)/(2000-1300)*(L-1300);
                        v02=min(v23,v24)+abs(v23-v24)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=3000
                        v01=min(v14,v15)+abs(v14-v15)/(3000-2000)*(L-2000);
                        v02=min(v24,v25)+abs(v24-v25)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    else
                        v01=v15;
                        v02=v25;
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    end
                end
                if miu>100&&miu<=200
                    if L<=500
                        v01=v21;
                        v02=v31;
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=900
                        v01=min(v21,v22)+abs(v21-v22)/(900-500)*(L-500);
                        v02=min(v31,v32)+abs(v31-v32)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=1300
                        v01=min(v22,v23)+abs(v22-v23)/(1300-900)*(L-900);
                        v02=min(v32,v33)+abs(v32-v33)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=2000
                        v01=min(v23,v24)+abs(v23-v24)/(2000-1300)*(L-1300);
                        v02=min(v33,v34)+abs(v33-v34)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=3000
                        v01=min(v24,v25)+abs(v24-v25)/(3000-2000)*(L-2000);
                        v02=min(v34,v35)+abs(v34-v35)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    else
                        v01=v25;
                        v02=v35;
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    end
                end

                if miu>200&&miu<=400
                    if L<=500
                        v01=v31;
                        v02=v41;
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=900
                        v01=min(v31,v32)+abs(v31-v32)/(900-500)*(L-500);
                        v02=min(v41,v42)+abs(v41-v42)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=1300
                        v01=min(v32,v33)+abs(v32-v33)/(1300-900)*(L-900);
                        v02=min(v42,v43)+abs(v42-v43)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=2000
                        v01=min(v33,v34)+abs(v33-v34)/(2000-1300)*(L-1300);
                        v02=min(v43,v44)+abs(v43-v44)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=3000
                        v01=min(v34,v35)+abs(v34-v35)/(3000-2000)*(L-2000);
                        v02=min(v44,v45)+abs(v44-v45)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    else
                        v01=v35;
                        v02=v45;
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    end
                end
                if miu>400&&miu<=730
                    if L<=500
                        v01=v41;
                        v02=v51;
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=900
                        v01=min(v41,v42)+abs(v41-v42)/(900-500)*(L-500);
                        v02=min(v51,v52)+abs(v51-v52)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=1300
                        v01=min(v42,v43)+abs(v42-v43)/(1300-900)*(L-900);
                        v02=min(v52,v53)+abs(v52-v53)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=2000
                        v01=min(v43,v44)+abs(v43-v44)/(2000-1300)*(L-1300);
                        v02=min(v53,v54)+abs(v53-v54)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=3000
                        v01=min(v44,v45)+abs(v44-v45)/(3000-2000)*(L-2000);
                        v02=min(v54,v55)+abs(v54-v55)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    else
                        v01=v45;
                        v02=v55;
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    end
                end

                if miu>730&&miu<=1000
                    if L<=500
                        v01=v51;
                        v02=v61;
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=900
                        v01=min(v51,v52)+abs(v51-v52)/(900-500)*(L-500);
                        v02=min(v61,v62)+abs(v61-v62)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=1300
                        v01=min(v52,v53)+abs(v52-v53)/(1300-900)*(L-900);
                        v02=min(v62,v63)+abs(v62-v63)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=2000
                        v01=min(v53,v54)+abs(v53-v54)/(2000-1300)*(L-1300);
                        v02=min(v63,v64)+abs(v63-v64)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=3000
                        v01=min(v54,v55)+abs(v54-v55)/(3000-2000)*(L-2000);
                        v02=min(v64,v65)+abs(v64-v65)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    else
                        v01=v55;
                        v02=v65;
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    end
                end

                if miu>1000
                    if L<=500
                        v=v61;
                    elseif L<=900
                        v=v61+(v62-v61)/(900-500)*(L-500);
                    elseif L<=1300
                        v=v62+(v63-v62)/(900-1300)*(L-1300);
                    elseif L<=2000
                        v=v63+(v64-v63)/(2000-1300)*(L-1300);
                    elseif L<=3000
                        v=v64+(v65-v64)/(3000-2000)*(L-2000);
                    else
                        v=v65;
                    end
                end
            end
            function [M_re]=NiuJu(m,Ma)
                if m>0.9
                    M_re=Ma;
                elseif m>0.7
                    M_re=0.82*Ma;
                elseif m>0.5
                    M_re=0.5*Ma;
                elseif m>0.2
                    M_re=1/9*Ma;
                elseif m>0.1
                    M_re=1/20*Ma;
                else
                    M_re=0*Ma;
                end
            end

        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            % 直接从编辑框控件获取值
            % 从app实例的TextArea_10标识符的Value属性中提取值
            
            % 读取用户输入的值
            D1o = str2double(app.TextArea.Value); % 抽油杆外径
            th1 = str2double(app.TextArea_2.Value); % 抽油杆壁厚
            D2o = str2double(app.TextArea_3.Value); % 油管外径
            th2 = str2double(app.TextArea_4.Value); % 油管壁厚
            D3o = str2double(app.TextArea_5.Value); % 套管外径
            th3 = str2double(app.TextArea_6.Value); % 套管壁厚
            H1 = str2double(app.TextArea_7.Value); % 油管内液面高度
            H2 = str2double(app.TextArea_8.Value); % 套管内液面高度
            e = str2double(app.TextArea_9.Value); % 螺杆泵偏心距
            D = str2double(app.TextArea_10.Value); % 螺杆泵截面直径


            % 计算内径
            D1i = D1o - 2 * th1; % 抽油杆内径
            D2i = D2o - 2 * th2; % 油管内径
            D3i = D3o - 2 * th3; % 套管内径
            
            app.D2i = D2i; % 将计算值存储为app属性
            app.D1o = D1o; % 将计算值存储为app属性方便其他功能组件调用
            app.th1 = th1; % 将壁厚值存储为app公共变量池中


            % 计算横截面积
            A1 = (pi/4) * (D2i^2 - D1o^2); % 油管内油液环空横截面积
            A2 = (pi/4) * (D3i^2 - D2o^2); % 套管内油液环空横截面积
            A3 = 4 * e * D; % 螺杆泵横截面积

            % 计算液面高度差
            DeltaH = H1 - H2;

            
            % % 创建一个字符串来存储所有的结果
            % results = sprintf([
            %     '抽油杆内径 D1i: %.4f m^2\n' ...
            %     '油管内径 D2i: %.4f m^2\n' ...
            %     '套管内径 D3i: %.4f m^2\n' ...
            %     '油管内油液环空横截面积 A1: %.4f m^2\n' ...
            %     '套管内油液环空横截面积 A2: %.4f m^2\n' ...
            %     '螺杆泵横截面积 A3: %.4f m^2\n' ...
            %     '油套液面高度差 DeltaH: %.4f m\n'
            %     ], D1i, D2i, D3i, A1, A2, A3, DeltaH);
            % 
            % % 使用 msgbox 显示所有结果
            % msgbox(results, '计算结果');


            g = 9.81; % 重力加速度，单位 m/s^2
            DeltaH = H1 - H2; % 油套液面高度差

            % 计算平衡时的液面高度
            H = (A1 * H1 + A2 * H2) / (A1 + A2);

            % 计算油液平衡时的时间
            t = sqrt(2 / g) * (1 / A3) * sqrt(A1 * A2 / (A1 + A2)) * sqrt(DeltaH);


            Q = str2double(app.TextArea_11.Value); % 日产量
            n = str2double(app.TextArea_12.Value); % 抽油杆转速
            dP = str2double(app.TextArea_13.Value);% 油套压差
            delta0 = str2double(app.TextArea_14.Value); % 定转子初始过盈量
            tau =  str2double(app.TextArea_15.Value);   % 油液粘度 
            L =  str2double(app.TextArea_16.Value);     % 抽油杆长度
            app.L =L;
            m = str2double(app.TextArea_17.Value); % 制动扭矩倍数
            D2i = app.D2i;  % 油管内径 
            D1o = app.D1o;  % 抽油杆外径
            
            % 计算抽油杆转速 (w)
            w = n * (2 * pi / 60); % 将转速从 r/min 转换为 rad/s

            % 计算螺杆泵举升液体的有功扭矩 (Mp)
            Mp = 110.5 * (Q / n) * dP;

            % 计算螺杆泵定转子摩擦扭矩 (Mb)
            Mb = 91.3 * delta0 - n^0.45 + 46.5;

            % 计算油液对抽油杆的摩擦扭矩 (Mr)
            % 注意：这里需要 D2i 和 D1o 的值，它们应该从之前的计算中获得
            % 假设 D2i 和 D1o 已经计算好并存储在相应变量中
            Mr = (pi * tau * w * D2i^2 * D1o^2 * L) / (D2i^2 - D1o^2) * 10^(-2);

            % 计算总负载扭矩(Ma)
            Ma = Mp + Mb + Mr; 


            %输入参数
            Ma=Ma;     %总负载扭矩
            L=str2double(app.TextArea_16.Value);      %抽油杆长度
            miu=str2double(app.TextArea_25.Value);    %粘度
            m=str2double(app.TextArea_17.Value);      %制动扭矩倍数
            for i=1:10
                [t]=ZhiDongShiJian(L,m);
                [v]=ZhiDongSuDu(L,m,miu);
                [M_re]=NiuJu(m,Ma);
                mm(i)=m;           %制动扭矩倍数
                Mt(i)=Ma*m;      %制动扭矩
                tt(i)=t;        %制动时间
                vv(i)=v;        %反转速度
                MM_re(i)=M_re;   %残余扭矩
                Ma=M_re;         %残余扭矩
                miu=miu*m;
                if M_re<=10
                    break
                end
            end
            tt3=sum(tt);
            vv3=vv(1);

            
            result = sprintf(['总制动时间 %.4f s   '...
                '最大反转速度为 %.4f rad/s \n'],tt3,vv3);
            
            % 输出表头
            fprintf('制动扭矩倍数  制动扭矩(N·m)  反转时间(s)  反转速度(rad/s) 残余扭矩（N·m）\n');
            result =sprintf([result...
                '\n制动扭矩倍数  制动扭矩(N·m)  反转时间(s)  反转速度(rad/s) 残余扭矩（N·m）\n'])
            % 输出数据，使用格式化字符串
            for i = 1:length(mm)
                result = sprintf([result...
                    '%12.2d  %-14.2f  %-10.2f  %-12.2f  %-10.2f\n'],mm(i), Mt(i), tt(i), vv(i), MM_re(i))
                
            end
            % Notice：使用;拼接字符串数组时严格要求字符串常量长度一致，否则报错元素维度不匹配
            % Notice:所谓输出字符打印的过程，就是printf翻译一段来自缓冲区的连续字符串的过程
            msgbox(result,'求解结果');

            function [t]=ZhiDongShiJian(L,m)
                t1=2.44283*exp(-m/0.19801)+0.94096;     %500m
                t2=3.62977*exp(-m/0.26325)+1.81065;     %900m
                t3=5.28677*exp(-m/0.33864)+2.68065;     %1300m
                t4=9.39202*exp(-m/0.35031)+4.23604;     %2000m
                t5=16.62899*exp(-m/0.32283)+6.15615;     %3000m
                if L<=500
                    t=t1;
                elseif L<=900
                    t=t1+(t2-t1)/(900-500)*(L-500);
                elseif L<=1300
                    t=t2+(t3-t2)/(1300-900)*(L-900);
                elseif L<=2000
                    t=t3+(t4-t3)/(2000-1300)*(L-1300);
                elseif L<=3000
                    t=t4+(t5-t4)/(3000-2000)*(L-2000);
                else
                    t=t5;
                end
            end
            function [v]=ZhiDongSuDu(L,m,miu)

                %50mPas-500m-900m-1300m-2000m-3000m
                v11=4.59689-4.52413*m;
                v12=4.76598-4.66184*m;
                v13=5.17411-4.90207*m;
                v14=5.3596-5.08732*m;
                v15=6.22626-5.70399*m;

                %100mPas-500m-900m-1300m-2000m-3000m
                v21=4.78079-4.5251*m;
                v22=5.10481-4.68797*m;
                v23=5.84068-5.3594*m;
                v24=7.13651-6.54315*m;
                v25=9.01556-8.2506*m;

                %200mPas-500m-900m-1300m-2000m-3000m
                v31=5.5445-5.17272*m;
                v32=6.76524-6.20375*m;
                v33=8.25032-7.56135*m;
                v34=10.85263-9.94045*m;
                v35=14.61229-13.36217*m;

                %400mPas-500m-900m-1300m-2000m-3000m
                v41=7.13651-6.54315*m;
                v42=10.10896-9.26059*m;
                v43=13.08614-11.98314*m;
                v44=18.39378-17.05107*m;
                v45=25.7541-23.56865*m;

                %730mPas-500m-900m-1300m-2000m-3000m
                v51=10.20192-9.34557*m;
                v52=15.63825-14.31738*m;
                v53=21.07809-19.29228*m;
                v54=30.5978-27.99838*m;
                v55=44.20116-40.43997*m;

                %1000mPas-500m-900m-1300m-2000m-3000m
                v61=12.71365-11.6424*m;
                v62=20.16524-18.45745*m;
                v63=27.61707-25.2724*m;
                v64=40.66023-37.20137*m;
                v65=59.29675-54.24669*m;

                if miu<=50
                    if L<=500
                        v=v11;
                    elseif L<=900
                        v=v11+(v12-v11)/(900-500)*(L-500);
                    elseif L<=1300
                        v=v12+(v13-v12)/(900-1300)*(L-1300);
                    elseif L<=2000
                        v=v13+(v14-v13)/(2000-1300)*(L-1300);
                    elseif L<=3000
                        v=v14+(v15-v14)/(3000-2000)*(L-2000);
                    else
                        v=v15;
                    end
                end

                if miu>50&&miu<=100
                    if L<=500
                        v01=v11;
                        v02=v21;
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=900
                        v01=min(v11,v12)+abs(v11-v12)/(900-500)*(L-500);
                        v02=min(v21,v22)+abs(v21-v22)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=1300
                        v01=min(v12,v13)+abs(v12-v13)/(1300-900)*(L-900);
                        v02=min(v22,v23)+abs(v22-v23)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=2000
                        v01=min(v13,v14)+abs(v13-v14)/(2000-1300)*(L-1300);
                        v02=min(v23,v24)+abs(v23-v24)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=3000
                        v01=min(v14,v15)+abs(v14-v15)/(3000-2000)*(L-2000);
                        v02=min(v24,v25)+abs(v24-v25)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    else
                        v01=v15;
                        v02=v25;
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    end
                end
                if miu>100&&miu<=200
                    if L<=500
                        v01=v21;
                        v02=v31;
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=900
                        v01=min(v21,v22)+abs(v21-v22)/(900-500)*(L-500);
                        v02=min(v31,v32)+abs(v31-v32)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=1300
                        v01=min(v22,v23)+abs(v22-v23)/(1300-900)*(L-900);
                        v02=min(v32,v33)+abs(v32-v33)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=2000
                        v01=min(v23,v24)+abs(v23-v24)/(2000-1300)*(L-1300);
                        v02=min(v33,v34)+abs(v33-v34)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=3000
                        v01=min(v24,v25)+abs(v24-v25)/(3000-2000)*(L-2000);
                        v02=min(v34,v35)+abs(v34-v35)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    else
                        v01=v25;
                        v02=v35;
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    end
                end

                if miu>200&&miu<=400
                    if L<=500
                        v01=v31;
                        v02=v41;
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=900
                        v01=min(v31,v32)+abs(v31-v32)/(900-500)*(L-500);
                        v02=min(v41,v42)+abs(v41-v42)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=1300
                        v01=min(v32,v33)+abs(v32-v33)/(1300-900)*(L-900);
                        v02=min(v42,v43)+abs(v42-v43)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=2000
                        v01=min(v33,v34)+abs(v33-v34)/(2000-1300)*(L-1300);
                        v02=min(v43,v44)+abs(v43-v44)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=3000
                        v01=min(v34,v35)+abs(v34-v35)/(3000-2000)*(L-2000);
                        v02=min(v44,v45)+abs(v44-v45)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    else
                        v01=v35;
                        v02=v45;
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    end
                end
                if miu>400&&miu<=730
                    if L<=500
                        v01=v41;
                        v02=v51;
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=900
                        v01=min(v41,v42)+abs(v41-v42)/(900-500)*(L-500);
                        v02=min(v51,v52)+abs(v51-v52)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=1300
                        v01=min(v42,v43)+abs(v42-v43)/(1300-900)*(L-900);
                        v02=min(v52,v53)+abs(v52-v53)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=2000
                        v01=min(v43,v44)+abs(v43-v44)/(2000-1300)*(L-1300);
                        v02=min(v53,v54)+abs(v53-v54)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=3000
                        v01=min(v44,v45)+abs(v44-v45)/(3000-2000)*(L-2000);
                        v02=min(v54,v55)+abs(v54-v55)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    else
                        v01=v45;
                        v02=v55;
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    end
                end

                if miu>730&&miu<=1000
                    if L<=500
                        v01=v51;
                        v02=v61;
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=900
                        v01=min(v51,v52)+abs(v51-v52)/(900-500)*(L-500);
                        v02=min(v61,v62)+abs(v61-v62)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=1300
                        v01=min(v52,v53)+abs(v52-v53)/(1300-900)*(L-900);
                        v02=min(v62,v63)+abs(v62-v63)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=2000
                        v01=min(v53,v54)+abs(v53-v54)/(2000-1300)*(L-1300);
                        v02=min(v63,v64)+abs(v63-v64)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=3000
                        v01=min(v54,v55)+abs(v54-v55)/(3000-2000)*(L-2000);
                        v02=min(v64,v65)+abs(v64-v65)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    else
                        v01=v55;
                        v02=v65;
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    end
                end

                if miu>1000
                    if L<=500
                        v=v61;
                    elseif L<=900
                        v=v61+(v62-v61)/(900-500)*(L-500);
                    elseif L<=1300
                        v=v62+(v63-v62)/(900-1300)*(L-1300);
                    elseif L<=2000
                        v=v63+(v64-v63)/(2000-1300)*(L-1300);
                    elseif L<=3000
                        v=v64+(v65-v64)/(3000-2000)*(L-2000);
                    else
                        v=v65;
                    end
                end
            end
            function [M_re]=NiuJu(m,Ma)
                M_re=0*Ma;
            end
            

        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %%%% 一、计算液面高度

            % 直接从编辑框控件获取值
            % 从app实例的TextArea_10标识符的Value属性中提取值
            
            % 读取用户输入的值
            D1o = str2double(app.TextArea.Value); % 抽油杆外径
            th1 = str2double(app.TextArea_2.Value); % 抽油杆壁厚
            D2o = str2double(app.TextArea_3.Value); % 油管外径
            th2 = str2double(app.TextArea_4.Value); % 油管壁厚
            D3o = str2double(app.TextArea_5.Value); % 套管外径
            th3 = str2double(app.TextArea_6.Value); % 套管壁厚
            H1 = str2double(app.TextArea_7.Value); % 油管内液面高度
            H2 = str2double(app.TextArea_8.Value); % 套管内液面高度
            e = str2double(app.TextArea_9.Value); % 螺杆泵偏心距
            D = str2double(app.TextArea_10.Value); % 螺杆泵截面直径


            % 计算内径
            D1i = D1o - 2 * th1; % 抽油杆内径
            D2i = D2o - 2 * th2; % 油管内径
            D3i = D3o - 2 * th3; % 套管内径
            
            app.D2i = D2i; % 将计算值存储为app属性
            app.D1o = D1o; % 将计算值存储为app属性方便其他功能组件调用
            app.th1 = th1; % 将壁厚值存储为app公共变量池中


            % 计算横截面积
            A1 = (pi/4) * (D2i^2 - D1o^2); % 油管内油液环空横截面积
            A2 = (pi/4) * (D3i^2 - D2o^2); % 套管内油液环空横截面积
            A3 = 4 * e * D; % 螺杆泵横截面积

            % 计算液面高度差
            DeltaH = H1 - H2;

            
            % % 创建一个字符串来存储所有的结果
            % results = sprintf([
            %     '抽油杆内径 D1i: %.4f m^2\n' ...
            %     '油管内径 D2i: %.4f m^2\n' ...
            %     '套管内径 D3i: %.4f m^2\n' ...
            %     '油管内油液环空横截面积 A1: %.4f m^2\n' ...
            %     '套管内油液环空横截面积 A2: %.4f m^2\n' ...
            %     '螺杆泵横截面积 A3: %.4f m^2\n' ...
            %     '油套液面高度差 DeltaH: %.4f m\n'
            %     ], D1i, D2i, D3i, A1, A2, A3, DeltaH);
            % 
            % % 使用 msgbox 显示所有结果
            % msgbox(results, '计算结果');


            g = 9.81; % 重力加速度，单位 m/s^2
            DeltaH = H1 - H2; % 油套液面高度差

            % 计算平衡时的液面高度
            H = (A1 * H1 + A2 * H2) / (A1 + A2);

            % 计算油液平衡时的时间
            t = sqrt(2 / g) * (1 / A3) * sqrt(A1 * A2 / (A1 + A2)) * sqrt(DeltaH);


            %%%% 二、计算负载扭矩


            Q = str2double(app.TextArea_11.Value); % 日产量
            n = str2double(app.TextArea_12.Value); % 抽油杆转速
            dP = str2double(app.TextArea_13.Value);% 油套压差
            delta0 = str2double(app.TextArea_14.Value); % 定转子初始过盈量
            tau =  str2double(app.TextArea_15.Value);   % 油液粘度 
            L =  str2double(app.TextArea_16.Value);     % 抽油杆长度
            app.L =L;
            m = str2double(app.TextArea_17.Value); % 制动扭矩倍数
            D2i = app.D2i;  % 油管内径 
            D1o = app.D1o;  % 抽油杆外径
            
            % 计算抽油杆转速 (w)
            w = n * (2 * pi / 60); % 将转速从 r/min 转换为 rad/s

            % 计算螺杆泵举升液体的有功扭矩 (Mp)
            Mp = 110.5 * (Q / n) * dP;

            % 计算螺杆泵定转子摩擦扭矩 (Mb)
            Mb = 91.3 * delta0 - n^0.45 + 46.5;

            % 计算油液对抽油杆的摩擦扭矩 (Mr)
            % 注意：这里需要 D2i 和 D1o 的值，它们应该从之前的计算中获得
            % 假设 D2i 和 D1o 已经计算好并存储在相应变量中
            Mr = (pi * tau * w * D2i^2 * D1o^2 * L) / (D2i^2 - D1o^2) * 10^(-2);

            % 计算总负载扭矩(Ma)
            Ma = Mp + Mb + Mr; 


            %%%% 三、计算制动时间
            
            %输入参数
            miu=tau;
            
            m= str2double(app.TextArea_17.Value); 
            Mt=Ma*m;
            

            tt3=ZhiDongShiJian(Ma,L,miu,m); % 求解制动扭矩
            vv3=FanZhuanSuDu(Ma,L,miu,m);   % 求解反转速度


            % 反转时间拟合值筛选器
            function tt3=ZhiDongShiJian(Ma,L,miu,m)
                %730mPas的制动扭矩与制动时间
                t1=1.84132*exp(-m/0.16103)+0.17304;       %500m
                t2=3.73*exp(-m/0.14702)+0.34622;       %1000m
                t3=5.64339*exp(-m/0.14644)+0.50965;       %1500m

                %73mPas的制动扭矩与制动时间
                t4=2.90962*exp(-m/0.35734)-0.00789;       %500m
                t5=5.46402*exp(-m/0.2298)+0.28763;       %1000m
                t6=6.76939*exp(-m/0.20879)+0.48239;       %1500m

                %7.3mPas的制动扭矩与制动时间
                t7=4.33981*exp(-m/0.23498)+0.30764;       %500m
                t8=8.19774*exp(-m/0.23737)+0.4909;       %1000m
                t9=12.42276*exp(-m/0.25009)+0.61157;       %1500m

                if miu<=7.3
                    if L<=500
                        tt3=t7;
                    elseif L<=1000
                        tt3=t7+abs(t8-t7)*(L-500)/(1000-500);
                    elseif L<=1500
                        tt3=t8+abs(t9-t8)*(L-1000)/(1500-1000);
                    else
                        tt3=t9+t9*(L-1500)/1500;
                    end
                end


                if miu>7.3&&miu<=73
                    if L<=500
                        tt1=t7;
                        tt2=t4;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    elseif L<=1000
                        tt1=min(t7,t8)+abs(t7-t8)*(L-500)/(1000-500);
                        tt2=min(t4,t5)+abs(t4-t5)*(L-500)/(1000-500);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    elseif L<=1500
                        tt1=min(t8,t9)+abs(t8-t9)*(L-1000)/(1500-1000);
                        tt2=min(t5,t6)+abs(t5-t6)*(L-1000)/(1500-1000);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    else
                        tt1=t9+t9*(L-1500)/1500;
                        tt2=t6+t6*(L-1500)/1500;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    end
                end


                if miu>73&&miu<730
                    if L<=500
                        tt1=t4;
                        tt2=t1;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    elseif L<=1000
                        tt1=min(t7,t8)+abs(t7-t8)*(L-500)/(1000-500);
                        tt2=min(t4,t5)+abs(t4-t5)*(L-500)/(1000-500);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    elseif L<=1500
                        tt1=min(t8,t9)+abs(t8-t9)*(L-1000)/(1500-1000);
                        tt2=min(t5,t6)+abs(t5-t6)*(L-1000)/(1500-1000);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    else
                        tt1=t6+t6*(L-1500)/1500;
                        tt2=t3+t3*(L-1500)/1500;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    end
                end


                if miu>=730
                    if L<=500
                        tt3=t1;
                    elseif L<=1000
                        tt3=t1+abs(t1-t2)*(L-500)/(1000-500);
                    elseif L<=1500
                        tt3=t2+abs(t2-t3)*(L-1000)/(1500-1000);
                    else
                        tt3=t3+t3*(L-1500)/1500;
                    end
                end
            end

            % 反转速度公式筛选器

            function vv3=FanZhuanSuDu(Ma,L,miu,m)

                %反转速度公式表
                %730mPas的制动扭矩与制动时间
                v1=27.60817*exp(-m/0.75485)-3.76247;       %500m
                v2=19.26888*exp(-m/0.71987)-2.12881;       %1000m
                v3=11.7411*exp(-m/0.72749)-1.3438;       %1500m

                %73mPas的制动扭矩与制动时间
                v4=6.92249*exp(-m/0.4918)-0.01399;       %500m
                v5=6.43102*exp(-m/0.4993)-0.11799;       %1000m
                v6=6.75866*exp(-m/0.59508)-0.39038;       %1500m

                %7.3mPas的制动扭矩与制动时间
                v7=7.9066*exp(-m/0.4513)-0.32138;       %500m
                v8=7.62441*exp(-m/0.44169)-0.24903;       %1000m
                v9=7.37815*exp(-m/0.43257)-0.18191;       %1500m
                

                % 反转速度筛选器
                if miu<=7.3
                    if L<=500
                        vv3=v7;
                    elseif L<=1000
                        vv3=v7+abs(v8-v7)*(L-500)/(1000-500);
                    elseif L<=1500
                        vv3=v8+abs(v9-v8)*(L-1000)/(1500-1000);
                    else
                        vv3=v9+v9*(L-1500)/1500;
                    end
                end


                if miu>7.3&&miu<=73
                    if L<=500
                        vv1=v7;
                        vv2=v4;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    elseif L<=1000
                        vv1=min(v7,v8)+abs(v7-v8)*(L-500)/(1000-500);
                        vv2=min(v4,v5)+abs(v4-v5)*(L-500)/(1000-500);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    elseif L<=1500
                        vv1=min(v8,v9)+abs(v8-v9)*(L-1000)/(1500-1000);
                        vv2=min(v5,v6)+abs(v5-v6)*(L-1000)/(1500-1000);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    else
                        vv1=v9+v9*(L-1500)/1500;
                        vv2=v6+v6*(L-1500)/1500;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    end
                end


                if miu>73&&miu<730
                    if L<=500
                        vv1=v4;
                        vv2=v1;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    elseif L<=1000
                        vv1=min(v7,v8)+abs(v7-v8)*(L-500)/(1000-500);
                        vv2=min(v4,v5)+abs(v4-v5)*(L-500)/(1000-500);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    elseif L<=1500
                        vv1=min(v8,v9)+abs(v8-v9)*(L-1000)/(1500-1000);
                        vv2=min(v5,v6)+abs(v5-v6)*(L-1000)/(1500-1000);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    else
                        vv1=v6+v6*(L-1500)/1500;
                        vv2=v3+v3*(L-1500)/1500;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    end
                end


                if miu>=730
                    if L<=500
                        vv=v1;
                    elseif L<=1000
                        vv3=v1+abs(v1-v2)*(L-500)/(1000-500);
                    elseif L<=1500
                        vv3=v2+abs(v2-v3)*(L-1000)/(1500-1000);
                    else
                        vv3=v3+v3*(L-1500)/1500;
                    end
                end
            end


            u = str2double(app.TextArea_18.Value); % 摩擦系数


            %%%% 四、计算机械式制动模型 %%%%

            %% 验证参数
            % u = 0.30;
            % vv3 = 10;
            % Py =0.279;
            % tt3 = 0.785;
            % Mt = 469;

            %%% 1.计算摩擦片压力Fy %%%
            % 接收参数Mt
            % 接收参数u
            % 产生参数Fy

            Fy = Mt/0.68254/(u^1.1853);

            %%% 2.计算机械式制动温度 %%%
            % 接收参数u
            % 接收参数vv3
            % 接收参数Fy
            % 接收参数tt3
            % 输出参数TJEMAX

            %% 验证参数
            %u =0.33;
            %vv3 = 10;
            %Fy = 2557;
            %tt3 =0.785;


            Fn = Fy/2687;        % 计算作用在摩擦片压力Fn
            J = Fn*vv3*tt3;      % 计算摩擦热能J

            format long;
            temp_matrix2 = [     % 创建液压式拟合参数表
                0.54995,-0.00146,0.3;
                0.7518,-0.00396,0.35;
                0.85598,-0.00314,0.4];

            temp_table2 = array2table(temp_matrix2, 'VariableNames', {'Column1', 'Column2', 'Column3'});
            if any(ismember(u, temp_table2.Column3))
                % 获取包含 u 的行的索引
                row_index2 = find(temp_table2.Column3 == u);

                % 获取对应的行
                matching_row2 = temp_table2(row_index2, :);

                % 假设你想获取第一行（如果存在多行，这里需要根据实际情况调整）
                a1 = matching_row2.Column1(1);
                b1 = matching_row2.Column2(1);

                % 计算 TEMAX
                TJEMAX = 20+a1*J+b1*J*J;
            else
                % 如果没有找到，执行其他操作
                index_down2 = temp_table2.Column3 > u;
                up_rows2 = temp_table2(index_down2, :);  % 计算大于u得到的参数表，取最上一行参数

                index_up2 = temp_table2.Column3 < u;
                down_rows2 = temp_table2(index_up2, :);  % 计算小于u得到的参数表，取最下一行参数

                aj1= down_rows2.Column1(end); % Notice:直接使用括号索引返回的是cell
                bj1= down_rows2.Column2(end); % Notice:使用点号+列名索引返回的才是值

                aj2 = up_rows2.Column1(1);
                bj2 = up_rows2.Column2(1);

                TJEMX1 = 20+aj1*J+bj1*J*J;
                TJEMX2 = 20+aj2*J+bj2*J*J;

                uja = up_rows2.Column3(1);  % 得到与 u 最近且小于 u 的摩擦系数
                TJEMAX = (u - uja)/0.05 * (TJEMX2 - TJEMX1) + TJEMX1; % 插值计算

            end


            results4 = sprintf(['平衡液面高度 %.4f m \n'...
                                '油液平衡耗时 %.4f s \n' ...
                                '总负载扭矩 %.4f N·m \n'...
                                '制动扭矩 %.4f N·m \n'...
                                '反转时间 %.4f s \n' ...
                                '反转速度 %.4f rad/s \n'...
                                '摩擦预紧力 %.4f N \n'...
                                '制动温度：%.4f C \n'],H,t,Ma,Mt,tt3,vv3,Fy,TJEMAX);
            
            % Notice:转换字符中没有\s,反斜杠要慎用
            msgbox(results4,'计算结果')


        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            %%%% 一、计算页面高度 %%%%
            
            % 直接从编辑框控件获取值
            % 从app实例的TextArea_10标识符的Value属性中提取值
            
            % 读取用户输入的值
            D1o = str2double(app.TextArea.Value); % 抽油杆外径
            th1 = str2double(app.TextArea_2.Value); % 抽油杆壁厚
            D2o = str2double(app.TextArea_3.Value); % 油管外径
            th2 = str2double(app.TextArea_4.Value); % 油管壁厚
            D3o = str2double(app.TextArea_5.Value); % 套管外径
            th3 = str2double(app.TextArea_6.Value); % 套管壁厚
            H1 = str2double(app.TextArea_7.Value); % 油管内液面高度
            H2 = str2double(app.TextArea_8.Value); % 套管内液面高度
            e = str2double(app.TextArea_9.Value); % 螺杆泵偏心距
            D = str2double(app.TextArea_10.Value); % 螺杆泵截面直径


            % 计算内径
            D1i = D1o - 2 * th1; % 抽油杆内径
            D2i = D2o - 2 * th2; % 油管内径
            D3i = D3o - 2 * th3; % 套管内径
            
            app.D2i = D2i; % 将计算值存储为app属性
            app.D1o = D1o; % 将计算值存储为app属性方便其他功能组件调用
            app.th1 = th1; % 将壁厚值存储为app公共变量池中


            % 计算横截面积
            A1 = (pi/4) * (D2i^2 - D1o^2); % 油管内油液环空横截面积
            A2 = (pi/4) * (D3i^2 - D2o^2); % 套管内油液环空横截面积
            A3 = 4 * e * D; % 螺杆泵横截面积

            % 计算液面高度差
            DeltaH = H1 - H2;

            
            % % 创建一个字符串来存储所有的结果
            % results = sprintf([
            %     '抽油杆内径 D1i: %.4f m^2\n' ...
            %     '油管内径 D2i: %.4f m^2\n' ...
            %     '套管内径 D3i: %.4f m^2\n' ...
            %     '油管内油液环空横截面积 A1: %.4f m^2\n' ...
            %     '套管内油液环空横截面积 A2: %.4f m^2\n' ...
            %     '螺杆泵横截面积 A3: %.4f m^2\n' ...
            %     '油套液面高度差 DeltaH: %.4f m\n'
            %     ], D1i, D2i, D3i, A1, A2, A3, DeltaH);
            % 
            % % 使用 msgbox 显示所有结果
            % msgbox(results, '计算结果');


            g = 9.81; % 重力加速度，单位 m/s^2
            DeltaH = H1 - H2; % 油套液面高度差

            % 计算平衡时的液面高度
            H = (A1 * H1 + A2 * H2) / (A1 + A2);

            % 计算油液平衡时的时间
            t = sqrt(2 / g) * (1 / A3) * sqrt(A1 * A2 / (A1 + A2)) * sqrt(DeltaH);


            %%%% 二、计算负载扭矩 %%%%

            Q = str2double(app.TextArea_11.Value); % 日产量
            n = str2double(app.TextArea_12.Value); % 抽油杆转速
            dP = str2double(app.TextArea_13.Value);% 油套压差
            delta0 = str2double(app.TextArea_14.Value); % 定转子初始过盈量
            tau =  str2double(app.TextArea_15.Value);   % 油液粘度 
            L =  str2double(app.TextArea_16.Value);     % 抽油杆长度
            app.L =L;
            m = str2double(app.TextArea_17.Value); % 制动扭矩倍数
            D2i = app.D2i;  % 油管内径 
            D1o = app.D1o;  % 抽油杆外径
            
            % 计算抽油杆转速 (w)
            w = n * (2 * pi / 60); % 将转速从 r/min 转换为 rad/s

            % 计算螺杆泵举升液体的有功扭矩 (Mp)
            Mp = 110.5 * (Q / n) * dP;

            % 计算螺杆泵定转子摩擦扭矩 (Mb)
            Mb = 91.3 * delta0 - n^0.45 + 46.5;

            % 计算油液对抽油杆的摩擦扭矩 (Mr)
            % 注意：这里需要 D2i 和 D1o 的值，它们应该从之前的计算中获得
            % 假设 D2i 和 D1o 已经计算好并存储在相应变量中
            Mr = (pi * tau * w * D2i^2 * D1o^2 * L) / (D2i^2 - D1o^2) * 10^(-2);

            % 计算总负载扭矩(Ma)
            Ma = Mp + Mb + Mr; 

            % 调用msgbox输出单个变量值得


            %%%% 三、计算制动时间 %%%%


            %输入参数
            miu=tau;
            m= str2double(app.TextArea_17.Value); 
            Mt=Ma*m;
            app.Mt =Mt;
            tt3=ZhiDongShiJian(Ma,L,miu,m); % 求解制动扭矩
            vv3=FanZhuanSuDu(Ma,L,miu,m);   % 求解反转速度


            % 反转时间拟合值筛选器
            function tt3=ZhiDongShiJian(Ma,L,miu,m)
                %730mPas的制动扭矩与制动时间
                t1=1.84132*exp(-m/0.16103)+0.17304;       %500m
                t2=3.73*exp(-m/0.14702)+0.34622;       %1000m
                t3=5.64339*exp(-m/0.14644)+0.50965;       %1500m

                %73mPas的制动扭矩与制动时间
                t4=2.90962*exp(-m/0.35734)-0.00789;       %500m
                t5=5.46402*exp(-m/0.2298)+0.28763;       %1000m
                t6=6.76939*exp(-m/0.20879)+0.48239;       %1500m

                %7.3mPas的制动扭矩与制动时间
                t7=4.33981*exp(-m/0.23498)+0.30764;       %500m
                t8=8.19774*exp(-m/0.23737)+0.4909;       %1000m
                t9=12.42276*exp(-m/0.25009)+0.61157;       %1500m

                if miu<=7.3
                    if L<=500
                        tt3=t7;
                    elseif L<=1000
                        tt3=t7+abs(t8-t7)*(L-500)/(1000-500);
                    elseif L<=1500
                        tt3=t8+abs(t9-t8)*(L-1000)/(1500-1000);
                    else
                        tt3=t9+t9*(L-1500)/1500;
                    end
                end


                if miu>7.3&&miu<=73
                    if L<=500
                        tt1=t7;
                        tt2=t4;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    elseif L<=1000
                        tt1=min(t7,t8)+abs(t7-t8)*(L-500)/(1000-500);
                        tt2=min(t4,t5)+abs(t4-t5)*(L-500)/(1000-500);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    elseif L<=1500
                        tt1=min(t8,t9)+abs(t8-t9)*(L-1000)/(1500-1000);
                        tt2=min(t5,t6)+abs(t5-t6)*(L-1000)/(1500-1000);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    else
                        tt1=t9+t9*(L-1500)/1500;
                        tt2=t6+t6*(L-1500)/1500;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    end
                end


                if miu>73&&miu<730
                    if L<=500
                        tt1=t4;
                        tt2=t1;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    elseif L<=1000
                        tt1=min(t7,t8)+abs(t7-t8)*(L-500)/(1000-500);
                        tt2=min(t4,t5)+abs(t4-t5)*(L-500)/(1000-500);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    elseif L<=1500
                        tt1=min(t8,t9)+abs(t8-t9)*(L-1000)/(1500-1000);
                        tt2=min(t5,t6)+abs(t5-t6)*(L-1000)/(1500-1000);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    else
                        tt1=t6+t6*(L-1500)/1500;
                        tt2=t3+t3*(L-1500)/1500;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    end
                end


                if miu>=730
                    if L<=500
                        tt3=t1;
                    elseif L<=1000
                        tt3=t1+abs(t1-t2)*(L-500)/(1000-500);
                    elseif L<=1500
                        tt3=t2+abs(t2-t3)*(L-1000)/(1500-1000);
                    else
                        tt3=t3+t3*(L-1500)/1500;
                    end
                end
            end

            % 反转速度公式筛选器

            function vv3=FanZhuanSuDu(Ma,L,miu,m)

                %反转速度公式表
                %730mPas的制动扭矩与制动时间
                v1=27.60817*exp(-m/0.75485)-3.76247;       %500m
                v2=19.26888*exp(-m/0.71987)-2.12881;       %1000m
                v3=11.7411*exp(-m/0.72749)-1.3438;       %1500m

                %73mPas的制动扭矩与制动时间
                v4=6.92249*exp(-m/0.4918)-0.01399;       %500m
                v5=6.43102*exp(-m/0.4993)-0.11799;       %1000m
                v6=6.75866*exp(-m/0.59508)-0.39038;       %1500m

                %7.3mPas的制动扭矩与制动时间
                v7=7.9066*exp(-m/0.4513)-0.32138;       %500m
                v8=7.62441*exp(-m/0.44169)-0.24903;       %1000m
                v9=7.37815*exp(-m/0.43257)-0.18191;       %1500m
                

                % 反转速度筛选器
                if miu<=7.3
                    if L<=500
                        vv3=v7;
                    elseif L<=1000
                        vv3=v7+abs(v8-v7)*(L-500)/(1000-500);
                    elseif L<=1500
                        vv3=v8+abs(v9-v8)*(L-1000)/(1500-1000);
                    else
                        vv3=v9+v9*(L-1500)/1500;
                    end
                end


                if miu>7.3&&miu<=73
                    if L<=500
                        vv1=v7;
                        vv2=v4;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    elseif L<=1000
                        vv1=min(v7,v8)+abs(v7-v8)*(L-500)/(1000-500);
                        vv2=min(v4,v5)+abs(v4-v5)*(L-500)/(1000-500);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    elseif L<=1500
                        vv1=min(v8,v9)+abs(v8-v9)*(L-1000)/(1500-1000);
                        vv2=min(v5,v6)+abs(v5-v6)*(L-1000)/(1500-1000);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    else
                        vv1=v9+v9*(L-1500)/1500;
                        vv2=v6+v6*(L-1500)/1500;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    end
                end


                if miu>73&&miu<730
                    if L<=500
                        vv1=v4;
                        vv2=v1;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    elseif L<=1000
                        vv1=min(v7,v8)+abs(v7-v8)*(L-500)/(1000-500);
                        vv2=min(v4,v5)+abs(v4-v5)*(L-500)/(1000-500);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    elseif L<=1500
                        vv1=min(v8,v9)+abs(v8-v9)*(L-1000)/(1500-1000);
                        vv2=min(v5,v6)+abs(v5-v6)*(L-1000)/(1500-1000);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    else
                        vv1=v6+v6*(L-1500)/1500;
                        vv2=v3+v3*(L-1500)/1500;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    end
                end


                if miu>=730
                    if L<=500
                        vv=v1;
                    elseif L<=1000
                        vv3=v1+abs(v1-v2)*(L-500)/(1000-500);
                    elseif L<=1500
                        vv3=v2+abs(v2-v3)*(L-1000)/(1500-1000);
                    else
                        vv3=v3+v3*(L-1500)/1500;
                    end
                end
            end


               %%%% 计算液压式制动模型 %%%%
            % 接收参数u                             % 摩擦系数
            % 接收参数vv3                           % 制动力矩
            % 接收参数Py                            % 制动时间
            % 接收参数tt3                           % 摩擦片压力
            % 输出参数TJEMAX                        % 制动杆转速

            u = str2double(app.TextArea_18.Value); % 摩擦系数


            %% 验证参数
            % u = 0.30;
            % vv3 = 10;
            % Py =0.279;
            % tt3 = 0.785;
            % Mt = 469;
            
            %%% 第一步：计算摩擦片压力Fy %%%
            % 接收参数Mt
            % 接收参数u
            % 产生参数Py

            Py = Mt/5096/u;                        % 摩擦片压力

            
            %%% 第二步：计算液压式式制动温度 %%%
            % 接收参数u
            % 接收参数vv3
            % 接收参数Fy
            % 接收参数tt3
            % 输出参数TJEMAX

            temp_matrix = [1.891,1.020,0.1;...     % 创建拟合变量参数阵列
                           5.402,0.869,0.15;...
                           7.556,0.775,0.2;...
                           9.991,0.840,0.25;...
                           12.603,0.826,0.3;...
                           14.286,0.796,0.35;...
                           16.884,0.787,0.4];

           
            % 创建表格
            temp_table = array2table(temp_matrix, 'VariableNames', {'Column1', 'Column2', 'Column3'});

            % 查找第三列中是否包含 u
            if any(ismember(u, temp_table.Column3))  % 使用 any 检查是否有匹配
                % 获取包含 u 的行的索引
                row_index = find(temp_table.Column3 == u);

                % 获取对应的行
                matching_row = temp_table(row_index, :);

                % 假设你想获取第一行（如果存在多行，这里需要根据实际情况调整）
                a = matching_row.Column1(1);
                b = matching_row.Column2(1);

                % 计算 TEMAX
                TEMAX = vv3 * tt3/(2*pi) * a * Py^b + 20;
            else
                % 如果没有找到，执行其他操作
                index_down = temp_table.Column3 > u;
                up_rows = temp_table(index_down, :);  % 计算大于u得到的参数表，取最上一行参数

                index_up = temp_table.Column3 < u;
                down_rows = temp_table(index_up, :);  % 计算小于u得到的参数表，取最下一行参数

                a1= down_rows.Column1(end); % Notice:直接使用括号索引返回的是cell
                b1= down_rows.Column2(end); % Notice:使用点号+列名索引返回的才是值

                a2 = up_rows.Column1(1);
                b2 = up_rows.Column2(1);

                TEMX1 = vv3 * tt3/(2*pi) * a1 * Py^ b1+ 20;
                TEMX2 = vv3 * tt3/(2*pi) *a2  * Py^b2 + 20;

                ua = down_rows.Column3(end);  % 得到与 u 最近且小于 u 的摩擦系数
                TEMAX = (u - ua)/0.05 * (TEMX2 - TEMX1) + TEMX1; % 插值计算
            end
           
           results4 = sprintf(['平衡液面高度 %.4f m \n'...
                                '油液平衡耗时 %.4f s \n' ...
                                '总负载扭矩 %.4f N·m \n'...
                                '制动扭矩 %.4f N·m \n'...
                                '反转时间 %.4f s \n' ...
                                '反转速度 %.4f rad/s \n'...
                                '摩擦预紧力 %.4f N \n'...
                                '制动温度：%.4f C \n'],H,t,Ma,Mt,tt3,vv3,Py,TEMAX);
            
            % Notice:转换字符中没有\s,反斜杠要慎用
            msgbox(results4,'计算结果')
            

        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % 直接从编辑框控件获取值
            % 从app实例的TextArea_10标识符的Value属性中提取值
            
            % 读取用户输入的值
            D1o = str2double(app.TextArea.Value); % 抽油杆外径
            th1 = str2double(app.TextArea_2.Value); % 抽油杆壁厚
            D2o = str2double(app.TextArea_3.Value); % 油管外径
            th2 = str2double(app.TextArea_4.Value); % 油管壁厚
            D3o = str2double(app.TextArea_5.Value); % 套管外径
            th3 = str2double(app.TextArea_6.Value); % 套管壁厚
            H1 = str2double(app.TextArea_7.Value); % 油管内液面高度
            H2 = str2double(app.TextArea_8.Value); % 套管内液面高度
            e = str2double(app.TextArea_9.Value); % 螺杆泵偏心距
            D = str2double(app.TextArea_10.Value); % 螺杆泵截面直径


            % 计算内径
            D1i = D1o - 2 * th1; % 抽油杆内径
            D2i = D2o - 2 * th2; % 油管内径
            D3i = D3o - 2 * th3; % 套管内径
            
            app.D2i = D2i; % 将计算值存储为app属性
            app.D1o = D1o; % 将计算值存储为app属性方便其他功能组件调用
            app.th1 = th1; % 将壁厚值存储为app公共变量池中


            % 计算横截面积
            A1 = (pi/4) * (D2i^2 - D1o^2); % 油管内油液环空横截面积
            A2 = (pi/4) * (D3i^2 - D2o^2); % 套管内油液环空横截面积
            A3 = 4 * e * D; % 螺杆泵横截面积

            % 计算液面高度差
            DeltaH = H1 - H2;

            
            % % 创建一个字符串来存储所有的结果
            % results = sprintf([
            %     '抽油杆内径 D1i: %.4f m^2\n' ...
            %     '油管内径 D2i: %.4f m^2\n' ...
            %     '套管内径 D3i: %.4f m^2\n' ...
            %     '油管内油液环空横截面积 A1: %.4f m^2\n' ...
            %     '套管内油液环空横截面积 A2: %.4f m^2\n' ...
            %     '螺杆泵横截面积 A3: %.4f m^2\n' ...
            %     '油套液面高度差 DeltaH: %.4f m\n'
            %     ], D1i, D2i, D3i, A1, A2, A3, DeltaH);
            % 
            % % 使用 msgbox 显示所有结果
            % msgbox(results, '计算结果');


            g = 9.81; % 重力加速度，单位 m/s^2
            DeltaH = H1 - H2; % 油套液面高度差

            % 计算平衡时的液面高度
            H = (A1 * H1 + A2 * H2) / (A1 + A2);

            % 计算油液平衡时的时间
            t = sqrt(2 / g) * (1 / A3) * sqrt(A1 * A2 / (A1 + A2)) * sqrt(DeltaH);


                  Q = str2double(app.TextArea_11.Value); % 日产量
            n = str2double(app.TextArea_12.Value); % 抽油杆转速
            dP = str2double(app.TextArea_13.Value);% 油套压差
            delta0 = str2double(app.TextArea_14.Value); % 定转子初始过盈量
            tau =  str2double(app.TextArea_15.Value);   % 油液粘度 
            L =  str2double(app.TextArea_16.Value);     % 抽油杆长度
            app.L =L;
            m = str2double(app.TextArea_17.Value); % 制动扭矩倍数
            D2i = app.D2i;  % 油管内径 
            D1o = app.D1o;  % 抽油杆外径
            
            % 计算抽油杆转速 (w)
            w = n * (2 * pi / 60); % 将转速从 r/min 转换为 rad/s

            % 计算螺杆泵举升液体的有功扭矩 (Mp)
            Mp = 110.5 * (Q / n) * dP;

            % 计算螺杆泵定转子摩擦扭矩 (Mb)
            Mb = 91.3 * delta0 - n^0.45 + 46.5;

            % 计算油液对抽油杆的摩擦扭矩 (Mr)
            % 注意：这里需要 D2i 和 D1o 的值，它们应该从之前的计算中获得
            % 假设 D2i 和 D1o 已经计算好并存储在相应变量中
            Mr = (pi * tau * w * D2i^2 * D1o^2 * L) / (D2i^2 - D1o^2) * 10^(-2);

            % 计算总负载扭矩(Ma)
            Ma = Mp + Mb + Mr; 

            % 调用msgbox输出单个变量值得
            %msgbox(['总负载扭矩 Ma: ', num2str(Ma), ' N·m'], '计算结果');


            %输入参数
            miu=tau;
            m=[0.1:0.1:1.4]';
            m= str2double(app.TextArea_17.Value); 
            Mt=Ma*m;
    
            tt3=ZhiDongShiJian(Ma,L,miu,m); % 求解制动扭矩
            vv3=FanZhuanSuDu(Ma,L,miu,m);   % 求解反转速度


            % 反转时间拟合值筛选器
            function tt3=ZhiDongShiJian(Ma,L,miu,m)
                %730mPas的制动扭矩与制动时间
                t1=1.84132*exp(-m/0.16103)+0.17304;       %500m
                t2=3.73*exp(-m/0.14702)+0.34622;       %1000m
                t3=5.64339*exp(-m/0.14644)+0.50965;       %1500m

                %73mPas的制动扭矩与制动时间
                t4=2.90962*exp(-m/0.35734)-0.00789;       %500m
                t5=5.46402*exp(-m/0.2298)+0.28763;       %1000m
                t6=6.76939*exp(-m/0.20879)+0.48239;       %1500m

                %7.3mPas的制动扭矩与制动时间
                t7=4.33981*exp(-m/0.23498)+0.30764;       %500m
                t8=8.19774*exp(-m/0.23737)+0.4909;       %1000m
                t9=12.42276*exp(-m/0.25009)+0.61157;       %1500m

                if miu<=7.3
                    if L<=500
                        tt3=t7;
                    elseif L<=1000
                        tt3=t7+abs(t8-t7)*(L-500)/(1000-500);
                    elseif L<=1500
                        tt3=t8+abs(t9-t8)*(L-1000)/(1500-1000);
                    else
                        tt3=t9+t9*(L-1500)/1500;
                    end
                end


                if miu>7.3&&miu<=73
                    if L<=500
                        tt1=t7;
                        tt2=t4;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    elseif L<=1000
                        tt1=min(t7,t8)+abs(t7-t8)*(L-500)/(1000-500);
                        tt2=min(t4,t5)+abs(t4-t5)*(L-500)/(1000-500);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    elseif L<=1500
                        tt1=min(t8,t9)+abs(t8-t9)*(L-1000)/(1500-1000);
                        tt2=min(t5,t6)+abs(t5-t6)*(L-1000)/(1500-1000);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    else
                        tt1=t9+t9*(L-1500)/1500;
                        tt2=t6+t6*(L-1500)/1500;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-7.3)/(73-7.3);
                    end
                end


                if miu>73&&miu<730
                    if L<=500
                        tt1=t4;
                        tt2=t1;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    elseif L<=1000
                        tt1=min(t7,t8)+abs(t7-t8)*(L-500)/(1000-500);
                        tt2=min(t4,t5)+abs(t4-t5)*(L-500)/(1000-500);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    elseif L<=1500
                        tt1=min(t8,t9)+abs(t8-t9)*(L-1000)/(1500-1000);
                        tt2=min(t5,t6)+abs(t5-t6)*(L-1000)/(1500-1000);
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    else
                        tt1=t6+t6*(L-1500)/1500;
                        tt2=t3+t3*(L-1500)/1500;
                        tt3=min(tt1,tt2)+abs(tt1-tt2)*(miu-73)/(730-73);
                    end
                end


                if miu>=730
                    if L<=500
                        tt3=t1;
                    elseif L<=1000
                        tt3=t1+abs(t1-t2)*(L-500)/(1000-500);
                    elseif L<=1500
                        tt3=t2+abs(t2-t3)*(L-1000)/(1500-1000);
                    else
                        tt3=t3+t3*(L-1500)/1500;
                    end
                end
            end

            % 反转速度公式筛选器

            function vv3=FanZhuanSuDu(Ma,L,miu,m)

                %反转速度公式表
                %730mPas的制动扭矩与制动时间
                v1=27.60817*exp(-m/0.75485)-3.76247;       %500m
                v2=19.26888*exp(-m/0.71987)-2.12881;       %1000m
                v3=11.7411*exp(-m/0.72749)-1.3438;       %1500m

                %73mPas的制动扭矩与制动时间
                v4=6.92249*exp(-m/0.4918)-0.01399;       %500m
                v5=6.43102*exp(-m/0.4993)-0.11799;       %1000m
                v6=6.75866*exp(-m/0.59508)-0.39038;       %1500m

                %7.3mPas的制动扭矩与制动时间
                v7=7.9066*exp(-m/0.4513)-0.32138;       %500m
                v8=7.62441*exp(-m/0.44169)-0.24903;       %1000m
                v9=7.37815*exp(-m/0.43257)-0.18191;       %1500m
                

                % 反转速度筛选器
                if miu<=7.3
                    if L<=500
                        vv3=v7;
                    elseif L<=1000
                        vv3=v7+abs(v8-v7)*(L-500)/(1000-500);
                    elseif L<=1500
                        vv3=v8+abs(v9-v8)*(L-1000)/(1500-1000);
                    else
                        vv3=v9+v9*(L-1500)/1500;
                    end
                end


                if miu>7.3&&miu<=73
                    if L<=500
                        vv1=v7;
                        vv2=v4;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    elseif L<=1000
                        vv1=min(v7,v8)+abs(v7-v8)*(L-500)/(1000-500);
                        vv2=min(v4,v5)+abs(v4-v5)*(L-500)/(1000-500);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    elseif L<=1500
                        vv1=min(v8,v9)+abs(v8-v9)*(L-1000)/(1500-1000);
                        vv2=min(v5,v6)+abs(v5-v6)*(L-1000)/(1500-1000);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    else
                        vv1=v9+v9*(L-1500)/1500;
                        vv2=v6+v6*(L-1500)/1500;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-7.3)/(73-7.3);
                    end
                end


                if miu>73&&miu<730
                    if L<=500
                        vv1=v4;
                        vv2=v1;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    elseif L<=1000
                        vv1=min(v7,v8)+abs(v7-v8)*(L-500)/(1000-500);
                        vv2=min(v4,v5)+abs(v4-v5)*(L-500)/(1000-500);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    elseif L<=1500
                        vv1=min(v8,v9)+abs(v8-v9)*(L-1000)/(1500-1000);
                        vv2=min(v5,v6)+abs(v5-v6)*(L-1000)/(1500-1000);
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    else
                        vv1=v6+v6*(L-1500)/1500;
                        vv2=v3+v3*(L-1500)/1500;
                        vv3=min(vv1,vv2)+abs(vv1-vv2)*(miu-73)/(730-73);
                    end
                end


                if miu>=730
                    if L<=500
                        vv=v1;
                    elseif L<=1000
                        vv3=v1+abs(v1-v2)*(L-500)/(1000-500);
                    elseif L<=1500
                        vv3=v2+abs(v2-v3)*(L-1000)/(1500-1000);
                    else
                        vv3=v3+v3*(L-1500)/1500;
                    end
                end
            end


            %%%% 能耗式模型计算 %%%%
            
            D1o =app.D1o;   % 获取抽油杆外径
            th1 = app.th1;  % 获取抽油杆壁厚
            L = app.L;      % 获取抽油杆长度
            Dens = str2double(app.TextArea_22.Value);
            Ex1 = str2double(app.TextArea_23.Value);
            miu1 = str2double(app.TextArea_24.Value);

            % Notice：在把程序放入GUI中前先进行局部验证，防止直接放入GUI中出现报错难以查找，增加开发时间
            % Notice: 将所有用到的输入变量分类汇总，方便Debug
            % Notice: 使用变量符号保持与论文同步，减少校准工作量

            % 总传入参数统计


            % 固定参数 Ke          电动势常数
            % 固定参数 kr          转矩常数

            % 从变量池中获取参数
            % 接收参数 D1o         抽油杆外径
            % 接收参数 th1         抽油杆壁厚
            % 接收参数 L           抽油杆长度

            % 新输入参数
            % 接收参数 Dens        抽油杆密度
            % 接收参数 Ex1         抽油杆弹性模量
            % 接收参数 miu1        泊松比

            % 过程弹窗输入参数
            % 接收参数R            实际电阻


            %% 1.计算模态角速度 %%
            % 接收参数 D1o         抽油杆外径
            % 接收参数 th1         抽油杆壁厚
            % 接收参数 L           抽油杆长度
            % 接收参数 Dens        抽油杆密度
            % 接收参数 Ex1         抽油杆弹性模量
            % 接收参数 miu1        泊松比
            % 输出参数 ww1         模态角速度 S1

            %验证参数
            % D1o = 38e-3;
            % th1 = 6e-3;
            % L = 1300;
            % Dens = 7800;
            % Ex1 = 210e9;
            % miu1 = 0.3;


            D1i = D1o -2*th1;            % 抽油杆内径
            G1 = Ex1*0.5/(1+miu1);       % 抽油杆剪切模量
            I_p1=pi/32*(D1o^4-D1i^4);    % 抽油杆极惯性距

            ww1 = 1/(4*L)*sqrt(G1/Dens)*2*pi;


            %% 2.求临界电阻 %%
            % 固定参数 Ke          电动势常数
            % 固定参数 kr          转矩常数
            Ke = 3.33;
            kr = 3.41;

            % 接收参数 D1o         抽油杆外径
            % 接收参数 th1         抽油杆壁厚
            % 接收参数 Dens        抽油杆密度
            % 接收参数 ww1         模态角速度 E1
            % 输出参数 Rl              临界电阻 S2

            %验证参数
            % D1o = 38e-3;
            % th1 = 6e-3;
            % Dens = 7800;
            % ww1 = 3.882;

            D1i = D1o -2*th1;               % 抽油杆内径
            I_p1=pi/32*(D1o^4-D1i^4);       % 抽油杆极惯性距

            Rl = Ke*kr/(Dens*I_p1)/(2*ww1); % 临界电阻 E2


            %% 3.求制动时间与制动力矩 %%
            % 接收参数 D1o         抽油杆外径
            % 接收参数 th1         抽油杆壁厚
            % 接收参数 Dens        抽油杆密度
            % 接收参数 Ke          电动势常数
            % 接收参数 kr          转矩常数
            % 接收参数 ww1         模态角速度
            % 接收参数 R           实际电阻
            % 输出参数 tz          制动时间
            % 输出参数 Te          电磁转矩最大值

            %验证参数
            % D1o = 38e-3;
            % th1 = 6e-3;
            % Dens = 7800;
            % Ke = 3.33;
            % kr =3.41;
            % ww1 = 3.8882;
             R = 1500;


            % 定义符号变量和常数

            c_e = Ke;                  %电动势常数
            c_T = kr;                  % 转矩常数
            a = 30e-3;                 % 常量
            b = 266e-3;                % 常量
            R = R;                     %  甲方输入电阻，Ω
            rou = Dens;                % 抽油杆密度,
            D1_out = D1o;              % 抽油杆外径, m
            t1 = th1;                  % 抽油杆壁厚, m
            D1_in = D1_out - 2 * t1;   % 抽油杆内径, m
            pi_val = pi;               % 角度 π
            W1 = ww1;                  %模态角速度，rad/s


            % Notice:在引用公共函数时第一个参数得是app对象
            [max_abs_Te,max_time,result_powered,t_min]=NengHaoZhiDong(app,c_e,c_T,a,b,R,rou,D1_out,D1_in,pi_val,W1);


            %Notice：sprintf的输入缓冲区变量类型要匹配，此处使用了num2str导致输出了弹窗数组消息
            results4 = sprintf(['平衡液面高度 %.4f m \n'...
                                '油液平衡耗时 %.4f s \n' ...
                                '总负载扭矩 %.4f N·m \n'...                       
                                '电磁转矩绝对值最大值为 %.4f N·m\n'...
                                '发生在时间:%.4f s\n'...
                                '电阻温度为::%.4f C\n'...
                                '制动时间:%.4f s\n'
                ],H,t,Ma,max_abs_Te,max_time,result_powered,t_min);
            msgbox(results4,'计算结果3')
            

        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            

            L=str2double(app.TextArea_16.Value);            % 螺杆长度
            miu=str2double(app.TextArea_25.Value);          % 液体粘度 单位mPas


            tt3=0.03774*L;
            m=0;%定值
            [vv3]=ZhiDongSuDu(L,m,miu);
            result = sprintf(['总制动时间 %.4f s \n'...
                              '最大反转速度 %.4f m/s'],tt3,vv3);
            msgbox(result,'计算结果')

            function [v]=ZhiDongSuDu(L,m,miu)

                %50mPas-500m-900m-1300m-2000m-3000m
                v11=11.2236-11.038*m;
                v12=12.3301-12.126*m;
                v13=13.4365-13.214*m;
                v14=15.3727-15.118*m;
                v15=18.1611-17.849*m;

                %100mPas-500m-900m-1300m-2000m-3000m
                v21=12.6067-12.3982*m;
                v22=14.8195-14.5746*m;
                v23=17.0324-16.751*m;
                v24=20.9049-20.5597*m;
                v25=26.4694-26.0151*m;

                %200mPas-500m-900m-1300m-2000m-3000m
                v31=15.3727-15.118*m;
                v32=19.7984-19.471*m;
                v33=24.224-23.824*m;
                v34=31.8377-30.870*m;
                v35=42.233-41.968*m;

                %400mPas-500m-900m-1300m-2000m-3000m
                v41=20.9664-20.759*m;
                v42=29.8437-29.5492*m;
                v43=38.7209-38.3388*m;
                v44=54.0977-53.2056*m;
                v45=76.3194-75.0109*m;

                %730mPas-500m-900m-1300m-2000m-3000m
                v51=30.03295-29.53743*m;
                v52=46.18677-45.42503*m;
                v53=62.34062-61.3127*m;
                v54=90.60984-89.11613*m;
                v55=130.99444-128.8353*m;

                %1000mPas-500m-900m-1300m-2000m-3000m
                v61=37.50132-36.8826*m;
                v62=59.62987-58.6466*m;
                v63=81.75842-80.4105*m;
                v64=120.4833-118.497*m;
                v65=175.8047-172.907*m;

                if miu<=50
                    if L<=500
                        v=v11;
                    elseif L<=900
                        v=v11+(v12-v11)/(900-500)*(L-500);
                    elseif L<=1300
                        v=v12+(v13-v12)/(900-1300)*(L-1300);
                    elseif L<=2000
                        v=v13+(v14-v13)/(2000-1300)*(L-1300);
                    elseif L<=3000
                        v=v14+(v15-v14)/(3000-2000)*(L-2000);
                    else
                        v=v15;
                    end
                end

                if miu>50&&miu<=100
                    if L<=500
                        v01=v11;
                        v02=v21;
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=900
                        v01=min(v11,v12)+abs(v11-v12)/(900-500)*(L-500);
                        v02=min(v21,v22)+abs(v21-v22)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=1300
                        v01=min(v12,v13)+abs(v12-v13)/(1300-900)*(L-900);
                        v02=min(v22,v23)+abs(v22-v23)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=2000
                        v01=min(v13,v14)+abs(v13-v14)/(2000-1300)*(L-1300);
                        v02=min(v23,v24)+abs(v23-v24)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=3000
                        v01=min(v14,v15)+abs(v14-v15)/(3000-2000)*(L-2000);
                        v02=min(v24,v25)+abs(v24-v25)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    else
                        v01=v15;
                        v02=v25;
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    end
                end
                if miu>100&&miu<=200
                    if L<=500
                        v01=v21;
                        v02=v31;
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=900
                        v01=min(v21,v22)+abs(v21-v22)/(900-500)*(L-500);
                        v02=min(v31,v32)+abs(v31-v32)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=1300
                        v01=min(v22,v23)+abs(v22-v23)/(1300-900)*(L-900);
                        v02=min(v32,v33)+abs(v32-v33)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=2000
                        v01=min(v23,v24)+abs(v23-v24)/(2000-1300)*(L-1300);
                        v02=min(v33,v34)+abs(v33-v34)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=3000
                        v01=min(v24,v25)+abs(v24-v25)/(3000-2000)*(L-2000);
                        v02=min(v34,v35)+abs(v34-v35)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    else
                        v01=v25;
                        v02=v35;
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    end
                end

                if miu>200&&miu<=400
                    if L<=500
                        v01=v31;
                        v02=v41;
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=900
                        v01=min(v31,v32)+abs(v31-v32)/(900-500)*(L-500);
                        v02=min(v41,v42)+abs(v41-v42)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=1300
                        v01=min(v32,v33)+abs(v32-v33)/(1300-900)*(L-900);
                        v02=min(v42,v43)+abs(v42-v43)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=2000
                        v01=min(v33,v34)+abs(v33-v34)/(2000-1300)*(L-1300);
                        v02=min(v43,v44)+abs(v43-v44)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=3000
                        v01=min(v34,v35)+abs(v34-v35)/(3000-2000)*(L-2000);
                        v02=min(v44,v45)+abs(v44-v45)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    else
                        v01=v35;
                        v02=v45;
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    end
                end
                if miu>400&&miu<=730
                    if L<=500
                        v01=v41;
                        v02=v51;
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=900
                        v01=min(v41,v42)+abs(v41-v42)/(900-500)*(L-500);
                        v02=min(v51,v52)+abs(v51-v52)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=1300
                        v01=min(v42,v43)+abs(v42-v43)/(1300-900)*(L-900);
                        v02=min(v52,v53)+abs(v52-v53)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=2000
                        v01=min(v43,v44)+abs(v43-v44)/(2000-1300)*(L-1300);
                        v02=min(v53,v54)+abs(v53-v54)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=3000
                        v01=min(v44,v45)+abs(v44-v45)/(3000-2000)*(L-2000);
                        v02=min(v54,v55)+abs(v54-v55)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    else
                        v01=v45;
                        v02=v55;
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    end
                end

                if miu>730&&miu<=1000
                    if L<=500
                        v01=v51;
                        v02=v61;
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=900
                        v01=min(v51,v52)+abs(v51-v52)/(900-500)*(L-500);
                        v02=min(v61,v62)+abs(v61-v62)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=1300
                        v01=min(v52,v53)+abs(v52-v53)/(1300-900)*(L-900);
                        v02=min(v62,v63)+abs(v62-v63)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=2000
                        v01=min(v53,v54)+abs(v53-v54)/(2000-1300)*(L-1300);
                        v02=min(v63,v64)+abs(v63-v64)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=3000
                        v01=min(v54,v55)+abs(v54-v55)/(3000-2000)*(L-2000);
                        v02=min(v64,v65)+abs(v64-v65)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    else
                        v01=v55;
                        v02=v65;
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    end
                end

                if miu>1000
                    if L<=500
                        v=v61;
                    elseif L<=900
                        v=v61+(v62-v61)/(900-500)*(L-500);
                    elseif L<=1300
                        v=v62+(v63-v62)/(900-1300)*(L-1300);
                    elseif L<=2000
                        v=v63+(v64-v63)/(2000-1300)*(L-1300);
                    elseif L<=3000
                        v=v64+(v65-v64)/(3000-2000)*(L-2000);
                    else
                        v=v65;
                    end
                end
            end
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            
            L=str2double(app.TextArea_16.Value);   % 螺杆长度
            miu=str2double(app.TextArea_25.Value);
            m=0;
            tt3=0.02515*L;

            [vv3]=ZhiDongSuDu(L,m,miu);
            
            result = sprintf(['总制动时间 %.4f s \n'...
                              '最大反转速度 %.4f m/s'],tt3,vv3);
            msgbox(result,'计算结果')
            function [v]=ZhiDongSuDu(L,m,miu)

                %50mPas-500m-900m-1300m-2000m-3000m
                v11=4.59689-4.52413*m;
                v12=4.76598-4.66184*m;
                v13=5.17411-4.90207*m;
                v14=5.3596-5.08732*m;
                v15=6.22626-5.70399*m;

                %100mPas-500m-900m-1300m-2000m-3000m
                v21=4.78079-4.5251*m;
                v22=5.10481-4.68797*m;
                v23=5.84068-5.3594*m;
                v24=7.13651-6.54315*m;
                v25=9.01556-8.2506*m;

                %200mPas-500m-900m-1300m-2000m-3000m
                v31=5.5445-5.17272*m;
                v32=6.76524-6.20375*m;
                v33=8.25032-7.56135*m;
                v34=10.85263-9.94045*m;
                v35=14.61229-13.36217*m;

                %400mPas-500m-900m-1300m-2000m-3000m
                v41=7.13651-6.54315*m;
                v42=10.10896-9.26059*m;
                v43=13.08614-11.98314*m;
                v44=18.39378-17.05107*m;
                v45=25.7541-23.56865*m;

                %730mPas-500m-900m-1300m-2000m-3000m
                v51=10.20192-9.34557*m;
                v52=15.63825-14.31738*m;
                v53=21.07809-19.29228*m;
                v54=30.5978-27.99838*m;
                v55=44.20116-40.43997*m;

                %1000mPas-500m-900m-1300m-2000m-3000m
                v61=12.71365-11.6424*m;
                v62=20.16524-18.45745*m;
                v63=27.61707-25.2724*m;
                v64=40.66023-37.20137*m;
                v65=59.29675-54.24669*m;

                if miu<=50
                    if L<=500
                        v=v11;
                    elseif L<=900
                        v=v11+(v12-v11)/(900-500)*(L-500);
                    elseif L<=1300
                        v=v12+(v13-v12)/(900-1300)*(L-1300);
                    elseif L<=2000
                        v=v13+(v14-v13)/(2000-1300)*(L-1300);
                    elseif L<=3000
                        v=v14+(v15-v14)/(3000-2000)*(L-2000);
                    else
                        v=v15;
                    end
                end

                if miu>50&&miu<=100
                    if L<=500
                        v01=v11;
                        v02=v21;
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=900
                        v01=min(v11,v12)+abs(v11-v12)/(900-500)*(L-500);
                        v02=min(v21,v22)+abs(v21-v22)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=1300
                        v01=min(v12,v13)+abs(v12-v13)/(1300-900)*(L-900);
                        v02=min(v22,v23)+abs(v22-v23)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=2000
                        v01=min(v13,v14)+abs(v13-v14)/(2000-1300)*(L-1300);
                        v02=min(v23,v24)+abs(v23-v24)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    elseif L<=3000
                        v01=min(v14,v15)+abs(v14-v15)/(3000-2000)*(L-2000);
                        v02=min(v24,v25)+abs(v24-v25)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    else
                        v01=v15;
                        v02=v25;
                        v=min(v01,v02)+abs(v01-v02)*(miu-50)/(100-50);
                    end
                end
                if miu>100&&miu<=200
                    if L<=500
                        v01=v21;
                        v02=v31;
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=900
                        v01=min(v21,v22)+abs(v21-v22)/(900-500)*(L-500);
                        v02=min(v31,v32)+abs(v31-v32)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=1300
                        v01=min(v22,v23)+abs(v22-v23)/(1300-900)*(L-900);
                        v02=min(v32,v33)+abs(v32-v33)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=2000
                        v01=min(v23,v24)+abs(v23-v24)/(2000-1300)*(L-1300);
                        v02=min(v33,v34)+abs(v33-v34)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    elseif L<=3000
                        v01=min(v24,v25)+abs(v24-v25)/(3000-2000)*(L-2000);
                        v02=min(v34,v35)+abs(v34-v35)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    else
                        v01=v25;
                        v02=v35;
                        v=min(v01,v02)+abs(v01-v02)*(miu-100)/(200-100);
                    end
                end

                if miu>200&&miu<=400
                    if L<=500
                        v01=v31;
                        v02=v41;
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=900
                        v01=min(v31,v32)+abs(v31-v32)/(900-500)*(L-500);
                        v02=min(v41,v42)+abs(v41-v42)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=1300
                        v01=min(v32,v33)+abs(v32-v33)/(1300-900)*(L-900);
                        v02=min(v42,v43)+abs(v42-v43)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=2000
                        v01=min(v33,v34)+abs(v33-v34)/(2000-1300)*(L-1300);
                        v02=min(v43,v44)+abs(v43-v44)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    elseif L<=3000
                        v01=min(v34,v35)+abs(v34-v35)/(3000-2000)*(L-2000);
                        v02=min(v44,v45)+abs(v44-v45)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    else
                        v01=v35;
                        v02=v45;
                        v=min(v01,v02)+abs(v01-v02)*(miu-200)/(400-200);
                    end
                end
                if miu>400&&miu<=730
                    if L<=500
                        v01=v41;
                        v02=v51;
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=900
                        v01=min(v41,v42)+abs(v41-v42)/(900-500)*(L-500);
                        v02=min(v51,v52)+abs(v51-v52)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=1300
                        v01=min(v42,v43)+abs(v42-v43)/(1300-900)*(L-900);
                        v02=min(v52,v53)+abs(v52-v53)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=2000
                        v01=min(v43,v44)+abs(v43-v44)/(2000-1300)*(L-1300);
                        v02=min(v53,v54)+abs(v53-v54)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    elseif L<=3000
                        v01=min(v44,v45)+abs(v44-v45)/(3000-2000)*(L-2000);
                        v02=min(v54,v55)+abs(v54-v55)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    else
                        v01=v45;
                        v02=v55;
                        v=min(v01,v02)+abs(v01-v02)*(miu-400)/(730-400);
                    end
                end

                if miu>730&&miu<=1000
                    if L<=500
                        v01=v51;
                        v02=v61;
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=900
                        v01=min(v51,v52)+abs(v51-v52)/(900-500)*(L-500);
                        v02=min(v61,v62)+abs(v61-v62)/(900-500)*(L-500);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=1300
                        v01=min(v52,v53)+abs(v52-v53)/(1300-900)*(L-900);
                        v02=min(v62,v63)+abs(v62-v63)/(1300-900)*(L-900);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=2000
                        v01=min(v53,v54)+abs(v53-v54)/(2000-1300)*(L-1300);
                        v02=min(v63,v64)+abs(v63-v64)/(2000-1300)*(L-1300);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    elseif L<=3000
                        v01=min(v54,v55)+abs(v54-v55)/(3000-2000)*(L-2000);
                        v02=min(v64,v65)+abs(v64-v65)/(3000-2000)*(L-2000);
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    else
                        v01=v55;
                        v02=v65;
                        v=min(v01,v02)+abs(v01-v02)*(miu-730)/(1000-730);
                    end
                end

                if miu>1000
                    if L<=500
                        v=v61;
                    elseif L<=900
                        v=v61+(v62-v61)/(900-500)*(L-500);
                    elseif L<=1300
                        v=v62+(v63-v62)/(900-1300)*(L-1300);
                    elseif L<=2000
                        v=v63+(v64-v63)/(2000-1300)*(L-1300);
                    elseif L<=3000
                        v=v64+(v65-v64)/(3000-2000)*(L-2000);
                    else
                        v=v65;
                    end
                end
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            %%% 暂存输入参数 %%% 

            % 保存空间参数到变量中，共计21个参数
        
             a = app.TextArea_4.Value; % 油管壁厚
             b = app.TextArea_5.Value; % 套管外径
             c = app.TextArea_6.Value; % 套管壁厚
             d = app.TextArea.Value;   % 抽油杆外径
             e = app.TextArea_2.Value; % 抽油杆壁厚
             f = app.TextArea_9.Value; % 螺杆泵偏心距
             g = app.TextArea_7.Value; % 油管内液面高度
             h = app.TextArea_8.Value; % 套管内液面高度
             i = app.TextArea_10.Value; % 螺杆泵截面直径
             j = app.TextArea_23.Value; % 抽油杆弹性模量
             k = app.TextArea_14.Value; % 定转子初始过盈量
             l = app.TextArea_24.Value; % 泊松比
             m = app.TextArea_13.Value; % 油套压差
             n = app.TextArea_18.Value; % 摩擦系数
             o = app.TextArea_19.Value; % 制动力矩
             p = app.TextArea_15.Value; % 油液粘度
             q = app.TextArea_12.Value; % 抽油杆转速
             r = app.TextArea_16.Value; % 抽油杆长度
             t = app.TextArea_22.Value; % 抽油杆密度
             u = app.TextArea_25.Value; % 液体粘性度
             v = app.TextArea_17.Value; % 制动扭矩倍数


            % 构造单次参数保存结构体
             parameters = struct(...
                 'A', a, ...
                 'B', b, ...
                 'C', c, ...
                 'D', d, ...
                 'E', e, ...
                 'F', f, ...
                 'G', g, ...
                 'H', h, ...
                 'I', i, ...
                 'J', j, ...
                 'K', k, ...
                 'L', l, ...
                 'M', m, ...
                 'N', n, ...
                 'O', o, ...
                 'P', p, ...
                 'Q', q, ...
                 'R', r, ...
                 'T', t, ...
                 'U', u, ...
                 'V', v ...
                 );
             % Notice：matlab结构体变量的字段名不能以中文字符开头
             % Notice：结构体的value属性不能直接访问控件方法
             fprintf("保存参数展示")
             parameters
             % 保存结构体到.mat文件
             save('parameters.mat', 'parameters');
             msgbox('参数保存成功')
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            %%% 读取暂存参数 %%% 
            load('parameters.mat', 'parameters');


            app.TextArea_4.Value = parameters.A; % 油管壁厚
            app.TextArea_5.Value = parameters.B; % 套管外径
            app.TextArea_6.Value = parameters.C; % 套管壁厚
            app.TextArea.Value = parameters.D;   % 抽油杆外径
            app.TextArea_2.Value = parameters.E; % 抽油杆壁厚
            app.TextArea_9.Value = parameters.F; % 螺杆泵偏心距
            app.TextArea_7.Value = parameters.G; % 油管内液面高度
            app.TextArea_8.Value = parameters.H; % 套管内液面高度
            app.TextArea_10.Value = parameters.I; % 螺杆泵截面直径
            app.TextArea_23.Value = parameters.J; % 抽油杆弹性模量
            app.TextArea_14.Value = parameters.K; % 定转子初始过盈量
            app.TextArea_24.Value = parameters.L; % 泊松比
            app.TextArea_13.Value = parameters.M; % 油套压差
            app.TextArea_18.Value = parameters.N; % 摩擦系数
            app.TextArea_19.Value = parameters.O; % 制动力矩
            app.TextArea_15.Value = parameters.P; % 油液粘度
            app.TextArea_12.Value = parameters.Q; % 抽油杆转速
            app.TextArea_16.Value = parameters.R; % 抽油杆长度
            app.TextArea_22.Value = parameters.T; % 抽油杆密度
            app.TextArea_25.Value = parameters.U; % 液体粘性度
            app.TextArea_17.Value = parameters.V; % 制动扭矩倍数


            msgbox('参数读取成功')


        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            %%% 复位输入参数 %%%
            app.TextArea_4.Value = "6.88e-3"; % 油管壁厚
            app.TextArea_5.Value = "168.28e-3"; % 套管外径
            app.TextArea_6.Value = "7.32e-3"; % 套管壁厚
            app.TextArea.Value = "38e-3";   % 抽油杆外径
            app.TextArea_2.Value = "6e-3"; % 抽油杆壁厚
            app.TextArea_9.Value = "16e-3"; % 螺杆泵偏心距
            app.TextArea_7.Value = "1000"; % 油管内液面高度
            app.TextArea_8.Value = "500"; % 套管内液面高度
            app.TextArea_10.Value = "52e-3"; % 螺杆泵截面直径
            app.TextArea_23.Value = "210e9"; % 抽油杆弹性模量
            app.TextArea_14.Value = "0.3"; % 定转子初始过盈量
            app.TextArea_24.Value = "0.3"; % 泊松比
            app.TextArea_13.Value = "1.75"; % 油套压差
            app.TextArea_18.Value = "0.3"; % 摩擦系数
            app.TextArea_19.Value = "469"; % 制动力矩
            app.TextArea_15.Value = "730"; % 油液粘度
            app.TextArea_12.Value = "150"; % 抽油杆转速
            app.TextArea_16.Value = "1300"; % 抽油杆长度
            app.TextArea_22.Value = "7800"; % 抽油杆密度
            app.TextArea_25.Value = "700"; % 液体粘性度
            app.TextArea_17.Value = "0.5"; % 制动扭矩倍数

            msgbox('参数复位成功')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '参数输入区';
            app.Label_3 = uilabel(app.Panel_3);
            app.Label_3.Text = '油管外径';
            app.TextArea_3 = uitextarea(app.Panel_3);
            app.TextArea_3.Value = {'114.3e-3'};
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '油管壁厚';
            app.TextArea_4 = uitextarea(app.Panel_3);
            app.TextArea_4.Value = {'6.88e-3'};
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '套管外径';
            app.TextArea_5 = uitextarea(app.Panel_3);
            app.TextArea_5.Value = {'168.28e-3'};
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '套管壁厚';
            app.TextArea_6 = uitextarea(app.Panel_3);
            app.TextArea_6.Value = {'7.32e-3'};
            app.Label_9 = uilabel(app.Panel_3);
            app.Label_9.Text = '螺杆泵偏心距';
            app.TextArea_9 = uitextarea(app.Panel_3);
            app.TextArea_9.Value = {'16e-3'};
            app.Label_10 = uilabel(app.Panel_3);
            app.Label_10.Text = '螺杆泵截面直径';
            app.TextArea_10 = uitextarea(app.Panel_3);
            app.TextArea_10.Value = {'52e-3'};
            app.Label_12 = uilabel(app.Panel_3);
            app.Label_12.Text = '日产量';
            app.TextArea_11 = uitextarea(app.Panel_3);
            app.TextArea_11.Value = {'43.2'};
            app.Label_13 = uilabel(app.Panel_3);
            app.Label_13.Text = '抽油杆转速';
            app.TextArea_12 = uitextarea(app.Panel_3);
            app.TextArea_12.Value = {'150'};
            app.Label_14 = uilabel(app.Panel_3);
            app.Label_14.Text = '油套压差';
            app.TextArea_13 = uitextarea(app.Panel_3);
            app.TextArea_13.Value = {'1.75'};
            app.Label_15 = uilabel(app.Panel_3);
            app.Label_15.Text = '定转子初始过盈量';
            app.TextArea_14 = uitextarea(app.Panel_3);
            app.TextArea_14.Value = {'0.3'};
            app.Label_16 = uilabel(app.Panel_3);
            app.Label_16.Text = '油液粘度';
            app.TextArea_15 = uitextarea(app.Panel_3);
            app.TextArea_15.Value = {'730'};
            app.Label_17 = uilabel(app.Panel_3);
            app.Label_17.Text = '抽油杆长度';
            app.TextArea_16 = uitextarea(app.Panel_3);
            app.TextArea_16.Value = {'1300'};
            app.TextArea_17 = uitextarea(app.Panel_3);
            app.TextArea_17.Value = {'0.5'};
            app.Label_19 = uilabel(app.Panel_3);
            app.Label_19.Text = '制动扭矩倍数';
            app.Label_21 = uilabel(app.Panel_3);
            app.Label_21.Text = '摩擦系数';
            app.TextArea_18 = uitextarea(app.Panel_3);
            app.TextArea_18.Value = {'0.3'};
            app.Label_22 = uilabel(app.Panel_3);
            app.Label_22.Text = '制动力矩';
            app.TextArea_19 = uitextarea(app.Panel_3);
            app.TextArea_19.Value = {'469'};
            app.Label_24 = uilabel(app.Panel_3);
            app.Label_24.Text = '抽油杆密度';
            app.TextArea_22 = uitextarea(app.Panel_3);
            app.TextArea_22.Value = {'7800'};
            app.Label_25 = uilabel(app.Panel_3);
            app.Label_25.Text = '抽油杆弹性模量';
            app.TextArea_23 = uitextarea(app.Panel_3);
            app.TextArea_23.Value = {'210e9'};
            app.Label_26 = uilabel(app.Panel_3);
            app.Label_26.Text = '泊松比';
            app.TextArea_24 = uitextarea(app.Panel_3);
            app.TextArea_24.Value = {'0.3'};
            app.Button_9 = uibutton(app.Panel_3, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Tag = 'competebuttom';
            app.Button_9.Text = '复位输入参数';
            app.Button_8 = uibutton(app.Panel_3, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Tag = 'competebuttom';
            app.Button_8.Text = '暂存输入参数';
            app.Label_27 = uilabel(app.Panel_3);
            app.Label_27.Text = '液体粘性度';
            app.TextArea_25 = uitextarea(app.Panel_3);
            app.TextArea_25.Value = {'700'};
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = '抽油杆外径';
            app.TextArea = uitextarea(app.Panel_3);
            app.TextArea.Value = {'38e-3'};
            app.Label_2 = uilabel(app.Panel_3);
            app.Label_2.Text = '抽油杆壁厚';
            app.TextArea_2 = uitextarea(app.Panel_3);
            app.TextArea_2.Value = {'6e-3'};
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = '油管内液面高度';
            app.TextArea_7 = uitextarea(app.Panel_3);
            app.TextArea_7.Value = {'1000'};
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = '套管内液面高度';
            app.TextArea_8 = uitextarea(app.Panel_3);
            app.TextArea_8.Value = {'500'};
            app.Button_15 = uibutton(app.Panel_3, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Tag = 'competebuttom';
            app.Button_15.Text = '载入上次参数';
            app.CheckBox = uicheckbox(app.Panel_3);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.Panel_3);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.Panel_3);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.Panel_3);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.Panel_3);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.Panel_3);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.Panel_3);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.Panel_3);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.Panel_3);
            app.CheckBox_9.Text = '';
            app.CheckBox_10 = uicheckbox(app.Panel_3);
            app.CheckBox_10.Text = '';
            app.CheckBox_11 = uicheckbox(app.Panel_3);
            app.CheckBox_11.Text = '';
            app.CheckBox_12 = uicheckbox(app.Panel_3);
            app.CheckBox_12.Text = '';
            app.CheckBox_13 = uicheckbox(app.Panel_3);
            app.CheckBox_13.Text = '';
            app.CheckBox_14 = uicheckbox(app.Panel_3);
            app.CheckBox_14.Text = '';
            app.CheckBox_15 = uicheckbox(app.Panel_3);
            app.CheckBox_15.Text = '';
            app.CheckBox_16 = uicheckbox(app.Panel_3);
            app.CheckBox_16.Text = '';
            app.CheckBox_17 = uicheckbox(app.Panel_3);
            app.CheckBox_17.Text = '';
            app.CheckBox_18 = uicheckbox(app.Panel_3);
            app.CheckBox_18.Text = '';
            app.CheckBox_19 = uicheckbox(app.Panel_3);
            app.CheckBox_19.Text = '';
            app.CheckBox_20 = uicheckbox(app.Panel_3);
            app.CheckBox_20.Text = '';
            app.CheckBox_21 = uicheckbox(app.Panel_3);
            app.CheckBox_21.Text = '';
            app.CheckBox_22 = uicheckbox(app.Panel_3);
            app.CheckBox_22.Text = '';
            app.CheckBox_23 = uicheckbox(app.Panel_3);
            app.CheckBox_23.Text = '';
            app.mLabel_17 = uilabel(app.Panel_3);
            app.mLabel_17.Text = 'm';
            app.mLabel_16 = uilabel(app.Panel_3);
            app.mLabel_16.Text = 'm';
            app.mLabel_15 = uilabel(app.Panel_3);
            app.mLabel_15.Text = 'm';
            app.mLabel_14 = uilabel(app.Panel_3);
            app.mLabel_14.Text = 'm';
            app.mLabel_13 = uilabel(app.Panel_3);
            app.mLabel_13.Text = 'm';
            app.mLabel_12 = uilabel(app.Panel_3);
            app.mLabel_12.Text = 'm';
            app.mLabel_11 = uilabel(app.Panel_3);
            app.mLabel_11.Text = 'm';
            app.mLabel_10 = uilabel(app.Panel_3);
            app.mLabel_10.Text = 'm';
            app.mLabel_9 = uilabel(app.Panel_3);
            app.mLabel_9.Text = 'm';
            app.mLabel_8 = uilabel(app.Panel_3);
            app.mLabel_8.Text = 'm';
            app.PaLabel = uilabel(app.Panel_3);
            app.PaLabel.Text = 'Pa';
            app.mmLabel = uilabel(app.Panel_3);
            app.mmLabel.Text = 'mm';
            app.m3dLabel = uilabel(app.Panel_3);
            app.m3dLabel.Text = 'm3/d';
            app.Label_41 = uilabel(app.Panel_3);
            app.Label_41.Text = '';
            app.MPaLabel = uilabel(app.Panel_3);
            app.MPaLabel.Text = 'MPa';
            app.NmLabel = uilabel(app.Panel_3);
            app.NmLabel.Text = 'N·m';
            app.Label_44 = uilabel(app.Panel_3);
            app.mPasLabel = uilabel(app.Panel_3);
            app.mPasLabel.Text = 'mPa·s';
            app.rminLabel = uilabel(app.Panel_3);
            app.rminLabel.Text = 'r/min';
            app.mLabel_7 = uilabel(app.Panel_3);
            app.mLabel_7.Text = 'm';
            app.kgmLabel = uilabel(app.Panel_3);
            app.kgmLabel.Text = 'kg/m³';
            app.mPasLabel_2 = uilabel(app.Panel_3);
            app.mPasLabel_2.Text = 'mPa·s';
            app.Label_50 = uilabel(app.Panel_3);
            app.Label_50.Text = '';
            app.Lamp = uilamp(app.Panel_3);
            app.Lamp_2 = uilamp(app.Panel_3);
            app.Lamp_3 = uilamp(app.Panel_3);
            app.Lamp_4 = uilamp(app.Panel_3);
            app.Lamp_5 = uilamp(app.Panel_3);
            app.Lamp_6 = uilamp(app.Panel_3);
            app.Lamp_7 = uilamp(app.Panel_3);
            app.Lamp_8 = uilamp(app.Panel_3);
            app.Lamp_9 = uilamp(app.Panel_3);
            app.Lamp_10 = uilamp(app.Panel_3);
            app.Lamp_11 = uilamp(app.Panel_3);
            app.Lamp_12 = uilamp(app.Panel_3);
            app.Lamp_13 = uilamp(app.Panel_3);
            app.Lamp_14 = uilamp(app.Panel_3);
            app.Lamp_15 = uilamp(app.Panel_3);
            app.Lamp_16 = uilamp(app.Panel_3);
            app.Lamp_17 = uilamp(app.Panel_3);
            app.Lamp_18 = uilamp(app.Panel_3);
            app.Lamp_19 = uilamp(app.Panel_3);
            app.Lamp_20 = uilamp(app.Panel_3);
            app.Lamp_21 = uilamp(app.Panel_3);
            app.Lamp_22 = uilamp(app.Panel_3);
            app.Lamp_23 = uilamp(app.Panel_3);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '自由释放';
            app.Button_13 = uibutton(app.Panel, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Tag = 'competebuttom';
            app.Button_13.Text = '卡泵工况';
            app.Button_14 = uibutton(app.Panel, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Tag = 'competebuttom';
            app.Button_14.Text = '停机工况';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '工具、参数说明';
            app.Label_51 = uilabel(app.Panel_2);
            app.Label_51.Text = '';
            app.Panel_6 = uipanel(app.Panel_2);
            app.Panel_6.Title = '机械式、液压式、能耗式复用参数';
            app.Label_52 = uilabel(app.Panel_6);
            app.Label_52.Text = '红色参数：用于计算液面高度';
            app.Label_53 = uilabel(app.Panel_6);
            app.Label_53.Text = '橙色参数：用于计算负载扭矩';
            app.Label_54 = uilabel(app.Panel_6);
            app.Label_54.Text = '黄色参数：用于计算制动时间';
            app.Panel_7 = uipanel(app.Panel_2);
            app.Panel_7.Title = '机械式、液压式参数';
            app.Label_55 = uilabel(app.Panel_7);
            app.Label_55.Text = '绿色参数：求解机械式制动压力、温度';
            app.Label_57 = uilabel(app.Panel_7);
            app.Label_57.Text = '绿色参数：求解液压式制动压力、温度';
            app.Panel_8 = uipanel(app.Panel_2);
            app.Panel_8.Title = '能耗式参数';
            app.Label_56 = uilabel(app.Panel_8);
            app.Label_56.Text = '蓝色参数：用于液压式计算模态角速度';
            app.Label_58 = uilabel(app.Panel_2);
            app.Label_58.Text = '按颜色顺序输参：红、橙、黄、绿、蓝';
            app.sLabel = uilabel(app.Panel_2);
            app.sLabel.Text = '求解时间在2s以内请勿高频点击';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Title = '正常停机';
            app.Button_12 = uibutton(app.Panel_5, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Tag = 'competebuttom';
            app.Button_12.Text = '无制动求解';
            app.Button_5 = uibutton(app.Panel_5, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Tag = 'competebuttom';
            app.Button_5.Text = '机械式模型求解';
            app.Button_7 = uibutton(app.Panel_5, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Tag = 'competebuttom';
            app.Button_7.Text = '液压式模型求解';
            app.Button_6 = uibutton(app.Panel_5, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Tag = 'competebuttom';
            app.Button_6.Text = '能耗式模型求解';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = '卡泵工况';
            app.Button_11 = uibutton(app.Panel_4, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Tag = 'competebuttom';
            app.Button_11.Text = '无制动求解';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '1.液面高度|油液平衡时间';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '2.负载扭矩|制动时间';
            app.Label_23 = uilabel(app.UIFigure);
            app.Label_23.Text = '4.能耗式制动';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed3, true);
            app.Button_3.Tag = 'competebuttom';
            app.Button_3.Text = '计算结果3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed4, true);
            app.Button_4.Tag = 'competebuttom';
            app.Button_4.Text = '计算结果4';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tag = 'competebuttom';
            app.Button.Text = '计算结果1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPused2, true);
            app.Button_2.Tag = 'competebuttom';
            app.Button_2.Text = '计算结果2';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '3.机械液压:摩擦压力|制动温度';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end